classdef DataViewer_Coreg2Cams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        ParametersPanel           matlab.ui.container.Panel
        enhancecontrastCheckBox   matlab.ui.control.CheckBox
        FliphorizontallyCheckBox  matlab.ui.control.CheckBox
        CreateNewButton           matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ShowinfolderButton        matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)        
        MainApp % Handle to DataViewer
    end
    
    properties (Access = public)
        tformFile % Structure with info of all tform files from the Labeo folder:  organized in descending order of creation datetime.
        currTform % Current Tform file (selected from the tformFile list).
        DataFolder % Folder containing the .dat files to be used to calculate the geometric transformation (Tform).
        LabeoFolder % " .../Documents/LabeoTech/Config/umIT/tformFiles"
    end
    
    methods (Access = {?DataViewer})
        
        function lookForTformFile(app)
            % This function looks for any tform files saved in
            % .../Documents/LabeoTech/Config/umIT/tformFiles/... folder.
            % If the folder doesn't exist, it creates one.
            % Output:
            %   tFormFile (struct): "dir" struct containing the info from all
            %   tform files found in the LabeoTech folder.
            
            % Find folder:
            if ispc
                % For windows:
                root = getenv('USERPROFILE');
            else
                % For unix:
                root = getenv('HOME');
            end
            % If the folder doesn't exist, create one:
            app.LabeoFolder = fullfile(root,'Documents', 'LabeoTech', 'Config','umIT','tformFiles');
            if ~isfolder(app.LabeoFolder)
                mkdir(app.LabeoFolder);
            end
            % Get the list of tform files:
            app.tformFile = dir(fullfile(app.LabeoFolder, 'coreg2cam_tform*.mat'));
            % Sort files from descending order of creation datetime:
            [~,idxSort] = sort([app.tformFile.datenum], 'descend');
            app.tformFile = app.tformFile(idxSort);
        end
        
        
        function showTformResult(app)
            % Plots the Tform results (registered images).
            if isempty(app.currTform)
                % Hide plot
                app.GridLayout.ColumnWidth{2} = 0;
                app.UIFigure.Position(3) = 200;
                return
            end
            app.GridLayout.ColumnWidth{2} = '1x';
            % Redimension figure:
            if app.UIFigure.Position(3) < 300
                app.UIFigure.Position(3) = 680;
            end
            % Load registered images:
            info = load(fullfile(app.currTform.folder, app.currTform.name));
            imshowpair(info.registeredImages{2}, info.registeredImages{1},'falsecolor','Parent',app.UIAxes);
            title(app.UIAxes, 'Coregistration result')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, visible)
            %
            app.DataFolder = MainApp.dummyProt.MainDir{1};
            if ~visible
                app.UIFigure.Visible = 'off';   
                app.lookForTformFile;
                return
            end
            
            % Look for existing Tform files:
            app.lookForTformFile;
            % Enable/Disable Buttons:
            if isempty(app.tformFile)
                app.ResetButton.Enable = 'off';
                app.ShowinfolderButton.Enable = 'off';
            end
            % Show existing tform results, if appliccable:
            if ~isempty(app.tformFile)
                % If there are backups, select the non-backup:
                idx = ~endsWith({app.tformFile.name},'_bkp.mat');
                if ~any(idx)
                    % If there are only backups, copy the most recent
                    % backup as a non-backup and update the tformFile list
                    % (Normally, this should not happen).
                    copyfile(fullfile(app.tformFile(1).folder, app.tformFile(1).name),...
                        fullfile(app.tformFile(1).folder, strrep(app.tformFile(1).name), '_bkp.mat','.mat'))
                    app.lookForTformFile;
                    app.currTform = app.tformFile(1); % Get the most recent file.
                end
                app.currTform = app.tformFile(idx);
            end
            app.showTformResult            
        end

        % Button pushed function: ShowinfolderButton
        function ShowinfolderButtonPushed(app, event)
            % Opens OS File managers in folder containing the tform files
            % used to coregister the data.
            
            if ispc
                winopen(app.currTform(1).folder);
            elseif ismac
                system(['open "' app.currTform(1).folder '"']);
            elseif isunix
                system(['xdg-open "' app.currTform(1).folder '"']);
            end
            
        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)
            % Creates a new tform file using the .dat files in the RawDir
            % from the currently opened file in DataViewer.
            % If there is already an existing file, the file will be moved
            % as backup.
            answer = 'Yes';
            
            if ~isempty(app.currTform)
                [~,name,~] = fileparts(app.currTform.name);
                answer = uiconfirm(app.UIFigure, 'There is already an existing Tform file. Do you want to replace it?', 'Replace Tform file?',"Options",{'Yes','No'},'DefaultOption',2);
            else
                name = 'coreg2cam_tform';
            end
            bkpName = [name '_' datestr(now(),'yyyymmddHHMMSS') '_bkp.mat'];
            
            if ~strcmpi(answer,'yes')
                return
            end
            % Perform coregistration:
            w = uiprogressdlg(app.UIFigure,'Message', 'Calculating geometric transformation...','Title','Please wait','Indeterminate','on');
            [tform,registeredImages,warnmsg] = genTform2Cams(app.DataFolder, app.enhancecontrastCheckBox.Value, app.FliphorizontallyCheckBox.Value);
            delete(w);
            if ~isempty(warnmsg)
                uialert(app.UIFigure,warnmsg, 'Failed to Register images!', "Icon","error","Modal",true,"CloseFcn",'uiresume(gcbf)');
                uiwait(app.UIFigure);
                return
            end
            if ~isempty(app.currTform)
                % Copy current tform file to as backup:
                copyfile(fullfile(app.currTform.folder, app.currTform.name), fullfile(app.currTform.folder, bkpName));
                % Save to tform file:
                save(fullfile(app.currTform.folder, app.currTform.name), 'tform','registeredImages');
            else
                % Create the first tform file and copy it to a backup file:
                save(fullfile(app.LabeoFolder, [name '.mat']), 'tform','registeredImages');
                copyfile(fullfile(app.LabeoFolder, [name '.mat']), fullfile(app.LabeoFolder, bkpName));
            end
            % Update tfom file info:
            app.lookForTformFile;
            app.currTform = app.tformFile(1);
            % Show image:
            app.showTformResult;
            % Enable buttons:
            app.ResetButton.Enable = 'on';
            app.ShowinfolderButton.Enable = 'on';
            % 
%             uialert(app.UIFigure, 'Geometric transformation (tform) info saved!','Finished!', 'Icon','info');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % User picks one of the backup files to overwrite the current
            % tform file.
            
            % Confirm:
            answer = uiconfirm(app.UIFigure, 'Replace the current Tform file by a backup?', 'Reset Tform file?', 'Options',{'Yes','No'}, 'DefaultOption',2);
            if ~strcmpi(answer,'yes')
                return
            end
            % Choose backup file:
            bkpFile = uigetfile(fullfile(app.currTform.folder,'coreg2cam_tform_*.mat'),'Select backup tform file','coreg2cam_tform_bkp.mat');
            if bkpFile == 0
                return
            end
            % Load backup data:
            try
                bkp = load(fullfile(app.currTform.folder, bkpFile));
            catch
                uialert(app.UIFigure, ['Backup file "' bkpFile '" not found in folder "' app.currTform.folder '"'],...
                    'Failed to load backup file','CloseFcn','uiresume(gcbf)','Icon',"error","Modal",true);
                uiwait(app.UIFigure);
                return
            end
            % Show a preview of the registration from the backup file:
            prevAns = false;
            f = uifigure('Name', 'Backup File registration preview (close fig. to continue)','CloseRequestFcn',@closeFig);
            ax =axes('Parent',f);
            imshowpair(bkp.registeredImages{2}, bkp.registeredImages{1},'falsecolor','Parent',ax);
            t = title(ax,bkpFile);t.Interpreter = 'none';            
            waitfor(f);
            if ~prevAns
                return
            end
            % Overwrite current tform file:
            save(fullfile(app.currTform.folder,app.currTform.name),'-struct','bkp');           
            % Update tform lists and plot the results:
            app.lookForTformFile;
            app.currTform = app.tformFile(1);
            app.showTformResult;
            uialert(app.UIFigure, 'Reset successfull','Done', 'Icon','info');
            
            %% Local callbacks
            function closeFig(src,~)
                % Preview figure close request callback
                Ans = uiconfirm(src, 'Use this backup file?', 'Confirm file selection', 'Options',{'Yes','No'}, 'DefaultOption',2);
                if strcmpi(Ans,'Yes')
                    prevAns = true;                    
                end
                delete(src);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ParametersPanel = uipanel(app.GridLayout2);
            app.ParametersPanel.Title = 'Parameters';
            app.enhancecontrastCheckBox = uicheckbox(app.ParametersPanel);
            app.enhancecontrastCheckBox.Tooltip = {'Enhance contrast of images to improve coregistration'};
            app.enhancecontrastCheckBox.Text = 'enhance contrast';
            app.enhancecontrastCheckBox.Value = true;
            app.FliphorizontallyCheckBox = uicheckbox(app.ParametersPanel);
            app.FliphorizontallyCheckBox.Tooltip = {'Check this box if the data from the camera #2 is mirrored.'};
            app.FliphorizontallyCheckBox.Text = 'Flip horizontally';
            app.CreateNewButton = uibutton(app.GridLayout2, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Tooltip = {'Create new Geometric transformation file. '; 'If a file already exists'; ' it will be saved as backup and replaced by the new one.'};
            app.CreateNewButton.Text = 'Create New';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Replace the curent Tform file with a backup.'};
            app.ResetButton.Text = 'Reset';
            app.ShowinfolderButton = uibutton(app.GridLayout2, 'push');
            app.ShowinfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinfolderButtonPushed, true);
            app.ShowinfolderButton.Tooltip = {'Show Tform files location.'};
            app.ShowinfolderButton.Text = 'Show in folder';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_Coreg2Cams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end