classdef DataViewer_evntManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        TriggerdetectionparametersPanel  matlab.ui.container.Panel
        mininterstimsSpinnerLabel       matlab.ui.control.Label
        mininterstimsSpinner            matlab.ui.control.Spinner
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        TriggertypeDropDownLabel        matlab.ui.control.Label
        TriggertypeDropDown             matlab.ui.control.DropDown
        ConditionlistPanel              matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        FileDropDownLabel               matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        GridLayout3                     matlab.ui.container.GridLayout
        DetectButton                    matlab.ui.control.Button
        SaveeventsButton                matlab.ui.control.Button
        SelectchannelsPanel             matlab.ui.container.Panel
        ChanGridLayout                  matlab.ui.container.GridLayout
        SelectcolumnsPanel              matlab.ui.container.Panel
        ColGridLayout                   matlab.ui.container.GridLayout
        TriggerinfoPanel                matlab.ui.container.Panel
        RecordinglengthEditFieldLabel   matlab.ui.control.Label
        RecordinglengthEditField        matlab.ui.control.NumericEditField
        TriggersdetectedLabel           matlab.ui.control.Label
        TriggersdetectedEditField       matlab.ui.control.NumericEditField
        NumberofconditionsLabel         matlab.ui.control.Label
        NumberofconditionsEditField     matlab.ui.control.NumericEditField
        DetectionthresholdEditFieldLabel  matlab.ui.control.Label
        DetectionthresholdEditField     matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        AvgtriallengthEditField_2Label  matlab.ui.control.Label
        AvgtriallengthEditField         matlab.ui.control.EditField
        AvgintertriallengthEditFieldLabel  matlab.ui.control.Label
        AvgintertriallengthEditField    matlab.ui.control.EditField
    end

    
    properties (Access = private)
        saveFolder % Folder where to save the "events.mat" file
        evObj % EventsManager class instance.
        fH  % plot figure handle array.
    end
    
    methods (Access = private)
        
        function showStats(app)
            % Updates fields of "Trigger info" panel.
            
            if isempty(app.evObj.timestamps)
                % Disable stats panel if trigger detection failed
                app.RecordinglengthEditField.Value = 0;
                app.DetectionthresholdEditField.Value = 0;
                app.TriggersdetectedEditField.Value = 0;
                app.NumberofconditionsEditField.Value = 0;
                app.AvgtriallengthEditField.Value = '0 (0/0)';
                app.AvgintertriallengthEditField.Value = '0 (0/0)';
                arrayfun(@(x) set(x, 'Enable','off'), app.TriggerinfoPanel.Children);
                return
            end
            
            app.RecordinglengthEditField.Value = double(size(app.evObj.AnalogIN,1)/app.evObj.sr);
            app.DetectionthresholdEditField.Value = double(app.evObj.trigThr);
            app.TriggersdetectedEditField.Value = double(sum(app.evObj.state));
            app.NumberofconditionsEditField.Value = length(app.evObj.eventNameList);
            
            deltaT = [diff(app.evObj.timestamps); nan];
            trialLen = mean(deltaT(app.evObj.state == 1), 'omitnan');
            app.AvgtriallengthEditField.Value = sprintf('%0.2f (%0.2f/(%0.2f)',trialLen,min(deltaT(app.evObj.state == 1),[],'all'), max(deltaT(app.evObj.state == 1),[],'all'));            
            
            interTrialLen = mean(deltaT(app.evObj.state == 0), 'omitnan');
            if isnan(interTrialLen)
                app.AvgintertriallengthEditField.Value = '0 (0/0)';
            else
                app.AvgintertriallengthEditField.Value = sprintf('%0.2f (%0.2f/(%0.2f)',trialLen,min(deltaT(app.evObj.state == 0),[],'all'), max(deltaT(app.evObj.state == 0),[],'all'));
            end
            % Re-enable elements:
            arrayfun(@(x) set(x, 'Enable','on'), app.TriggerinfoPanel.Children);                        
        end
        
        function showPlots(app)
            % Displays the plots of the selected channels.
            
            % Delete existing plots
            if ~isempty(app.fH) & isvalid(app.fH)%#ok
                close(app.fH);
            end
            
            if isempty(app.evObj.timestamps)
                % PLot All channels when a trigger detection fails:                
                app.fH = app.evObj.plotAnalogIN({app.ChanGridLayout.Children.Text});
            else               
                % Create plots for the selected channels:
                app.fH = app.evObj.plotAnalogIN(app.evObj.trigChanName); 
            end
            % Put plot figures next to the main app.            
            cascadeArr = 10*(0:length(app.fH)-1);
            for ii = 1:length(app.fH)
                app.fH(ii).Position(1) = sum([app.UIFigure.Position([1 3]),cascadeArr(ii),5]);
                app.fH(ii).Position(2) = app.UIFigure.OuterPosition(4) + app.UIFigure.OuterPosition(2) - app.fH(ii).Position(4)-50 - cascadeArr(ii);
            end
                       
        end
        
        function updateAIchannelList(app)
            % Updates the list of channels in the "Select Channels" panel.
            % Camera triggers excluded.
            
            if isempty(app.evObj) || ~isvalid(app.evObj)
                return
            end
            % Populate Panel with list of available AnalogIN channels:
            chanList = setdiff(app.evObj.AIChanList, {'CameraTrig', 'CameraTrig2'}, 'stable') ; % Hide Camera triggers from User.
            app.ChanGridLayout.RowHeight = repmat({25},size(chanList));
            for ii = 1:length(chanList)
                c = uicheckbox('Parent',app.ChanGridLayout, 'Text', chanList{ii},...
                    'Value', any(strcmpi(chanList{ii}, app.evObj.trigChanName)), 'ValueChangedFcn', @lockCheckBox);
                c.Layout.Row = ii; 
            end
            if ~any([app.ChanGridLayout.Children.Value])
                app.ChanGridLayout.Children(1).Value = true; % Select first channel on the list.
            end
            % Show panel
            app.GridLayout.ColumnWidth{2} = 150;
            app.UIFigure.Position(3) = app.UIFigure.Position(3) + 150;
            %%%%% Custom callback ---------------------------------------------
            function lockCheckBox(src,~)
                % This callback avoids the unchecking of the last checked box.
                idxState = arrayfun(@(x) x.Value, src.Parent.Children);
                if ~any(idxState)
                    src.Value = 1;
                end
            end    
        end
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rawFolder, saveFolder)
            % 
            app.UIFigure.Visible = 'off';
            % Hide the two columns in the middle:            
            app.GridLayout.ColumnWidth([2,3]) = {0,0};
            app.UIFigure.Position(3) = app.UIFigure.Position(3) - 320; %Resize figure;
            movegui(app.UIFigure,'northwest');
            
            app.saveFolder = saveFolder;
            % Instantiate EventsManager object:            
            app.evObj = EventsManager(rawFolder);
            % Update list of available channels:
            app.updateAIchannelList;
            app.UIFigure.Visible = 'on';
            % If there are no AnalogIN data in the raw folder, raise error
            % message:
            if isempty(app.evObj.AnalogIN)
                uialert(app.UIFigure, ['Failed to find analog signals in folder "' rawFolder '". This app will close.'], 'Error!',"CloseFcn",@(src,evnt) app.UIFigureCloseRequest)
            else
                % If internal stims were already detected, update channel list
                % and plot triggers.
                if ~isempty(app.evObj.eventID)
                    app.showStats;                    
                    app.showPlots;                                   
                end
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.fH) & isvalid(app.fH) %#ok
                close(app.fH);
            end
            delete(app)            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            % Detects triggers in the selected channels and plots the results.
            % If no triggers are detected, all channels are plotted for
            % visual inspection.
            
            % Get trigger channel name:
            trigNames = {app.ChanGridLayout.Children.Text};            
            app.evObj.trigChanName = trigNames([app.ChanGridLayout.Children.Value]);
            % Update threshold level:
            new_thr = str2double(app.ThresholdEditField.Value);                       
            if isnan(new_thr)
                app.evObj.trigThr = 'auto';
            else
                app.evObj.trigThr = new_thr;
            end
            % Update min intertrial length:
            app.evObj.minInterStim = app.mininterstimsSpinner.Value;
            % Detect triggers:
            app.evObj.getTriggers;
            % If no triggers were detected, show warning message;            
            if isempty(app.evObj.timestamps)                
                uialert(app.UIFigure,'Failed to detect triggers in selected channels! All channels will be plotted for visual inspection.','Trigger detection failed!', 'Modal',true,'Icon',"warning", "CloseFcn",'uiresume(gcbf)');                                               
                uiwait(app.UIFigure);
                app.SaveeventsButton.Enable = 'off';
            end
            % Update stats:
            app.showStats;
            % Show Plots:
            app.showPlots;            
            app.SaveeventsButton.Enable = 'on';
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            switch lower(app.TypeDropDown.Value)
                case 'none'
                    app.GridLayout.ColumnWidth{3} = 0;
                    app.ColGridLayout.Children = [];
                    set(app.FileDropDown, 'Items',{''}, 'Enable','off');                     
                case 'csv'
                    app.GridLayout.ColumnWidth{3} = '1x';  pause(.2);drawnow()
                    if app.SelectcolumnsPanel.Position(3) < 150                        
                        app.UIFigure.Position(3) = app.UIFigure.Position(3) + 150;
                    end
                    set(app.FileDropDown, 'Items',{'A','B'}, 'Enable','on');
                case 'vpixx'
                    app.GridLayout.ColumnWidth{3} = 0;
                    app.ColGridLayout.Children = [];
                    set(app.FileDropDown, 'Items',{'C','D'}, 'Enable','on');
                otherwise
                    error('Unknown file type')
            end
                                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Events Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TriggerdetectionparametersPanel = uipanel(app.GridLayout2);
            app.TriggerdetectionparametersPanel.Tooltip = {''};
            app.TriggerdetectionparametersPanel.Title = 'Trigger detection parameters';
            app.mininterstimsSpinnerLabel = uilabel(app.TriggerdetectionparametersPanel);
            app.mininterstimsSpinnerLabel.Text = 'min. interstim (s)';
            app.mininterstimsSpinner = uispinner(app.TriggerdetectionparametersPanel);
            app.mininterstimsSpinner.Step = 0.2;
            app.mininterstimsSpinner.Limits = [0.5 Inf];
            app.mininterstimsSpinner.Tooltip = {'Minimal time between stimuli in seconds (used to identify burst stimuli).'};
            app.mininterstimsSpinner.Value = 2;
            app.ThresholdEditFieldLabel = uilabel(app.TriggerdetectionparametersPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.TriggerdetectionparametersPanel, 'text');
            app.ThresholdEditField.Tooltip = {'Trigger detection threshold ("auto" or a numeric value in Volts).'};
            app.ThresholdEditField.Value = 'auto';
            app.TriggertypeDropDownLabel = uilabel(app.TriggerdetectionparametersPanel);
            app.TriggertypeDropDownLabel.Enable = 'off';
            app.TriggertypeDropDownLabel.Text = 'Trigger type';
            app.TriggertypeDropDown = uidropdown(app.TriggerdetectionparametersPanel);
            app.TriggertypeDropDown.Items = {'EdgeSet', 'EdgeToggle'};
            app.TriggertypeDropDown.Enable = 'off';
            app.TriggertypeDropDown.Tooltip = {'Type of trigger. EdgeSet: rising and falling edges of a pulse mark the start and end of an event. '; 'EdgeToggle: two consecutive pulses mark the start and end of an event.'};
            app.TriggertypeDropDown.Value = 'EdgeSet';
            app.ConditionlistPanel = uipanel(app.GridLayout2);
            app.ConditionlistPanel.Title = 'Condition list';
            app.TypeDropDownLabel = uilabel(app.ConditionlistPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ConditionlistPanel);
            app.TypeDropDown.Items = {'none', 'CSV', 'Vpixx'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'none';
            app.FileDropDownLabel = uilabel(app.ConditionlistPanel);
            app.FileDropDownLabel.Text = 'File:';
            app.FileDropDown = uidropdown(app.ConditionlistPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.Enable = 'off';
            app.FileDropDown.Value = {};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.DetectButton = uibutton(app.GridLayout3, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Tooltip = {'Detect and plot events.'};
            app.DetectButton.Text = 'Detect';
            app.SaveeventsButton = uibutton(app.GridLayout3, 'push');
            app.SaveeventsButton.Enable = 'off';
            app.SaveeventsButton.Tooltip = {'Save info to "events.mat" file.'};
            app.SaveeventsButton.Text = 'Save events';
            app.SelectchannelsPanel = uipanel(app.GridLayout);
            app.SelectchannelsPanel.Tooltip = {'Select one or more channels containing the triggers. If two or more channels are chosen'; ' each channel will be considered as a condition'; ' unless a condition file is set.'};
            app.SelectchannelsPanel.Title = 'Select channel(s) ';
            app.ChanGridLayout = uigridlayout(app.SelectchannelsPanel);
            app.SelectcolumnsPanel = uipanel(app.GridLayout);
            app.SelectcolumnsPanel.Tooltip = {'Select one or more columns from the .CSV file. For multiple selection'; ' each row will be concatenated to a single condition.'};
            app.SelectcolumnsPanel.Title = 'Select column(s)';
            app.ColGridLayout = uigridlayout(app.SelectcolumnsPanel);
            app.TriggerinfoPanel = uipanel(app.GridLayout);
            app.TriggerinfoPanel.Title = 'Trigger info';
            app.RecordinglengthEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.RecordinglengthEditFieldLabel.Enable = 'off';
            app.RecordinglengthEditFieldLabel.Text = 'Recording length:';
            app.RecordinglengthEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.RecordinglengthEditField.Limits = [0 Inf];
            app.RecordinglengthEditField.ValueDisplayFormat = '%0.2f';
            app.RecordinglengthEditField.Editable = 'off';
            app.RecordinglengthEditField.Enable = 'off';
            app.TriggersdetectedLabel = uilabel(app.TriggerinfoPanel);
            app.TriggersdetectedLabel.Enable = 'off';
            app.TriggersdetectedLabel.Text = 'Triggers detected:';
            app.TriggersdetectedEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.TriggersdetectedEditField.Limits = [0 Inf];
            app.TriggersdetectedEditField.Editable = 'off';
            app.TriggersdetectedEditField.Enable = 'off';
            app.NumberofconditionsLabel = uilabel(app.TriggerinfoPanel);
            app.NumberofconditionsLabel.Enable = 'off';
            app.NumberofconditionsLabel.Text = 'Number of conditions:';
            app.NumberofconditionsEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.NumberofconditionsEditField.Limits = [0 Inf];
            app.NumberofconditionsEditField.Editable = 'off';
            app.NumberofconditionsEditField.Enable = 'off';
            app.DetectionthresholdEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.DetectionthresholdEditFieldLabel.Enable = 'off';
            app.DetectionthresholdEditFieldLabel.Text = 'Detection threshold:';
            app.DetectionthresholdEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.DetectionthresholdEditField.Limits = [0 Inf];
            app.DetectionthresholdEditField.ValueDisplayFormat = '%0.2f';
            app.DetectionthresholdEditField.Editable = 'off';
            app.DetectionthresholdEditField.Enable = 'off';
            app.sLabel = uilabel(app.TriggerinfoPanel);
            app.sLabel.Enable = 'off';
            app.sLabel.Text = 's';
            app.VLabel = uilabel(app.TriggerinfoPanel);
            app.VLabel.Enable = 'off';
            app.VLabel.Text = 'V';
            app.sLabel_2 = uilabel(app.TriggerinfoPanel);
            app.sLabel_2.Enable = 'off';
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.TriggerinfoPanel);
            app.sLabel_3.Enable = 'off';
            app.sLabel_3.Text = 's';
            app.AvgtriallengthEditField_2Label = uilabel(app.TriggerinfoPanel);
            app.AvgtriallengthEditField_2Label.Enable = 'off';
            app.AvgtriallengthEditField_2Label.Text = 'Avg. trial length :';
            app.AvgtriallengthEditField = uieditfield(app.TriggerinfoPanel, 'text');
            app.AvgtriallengthEditField.Enable = 'off';
            app.AvgtriallengthEditField.Tooltip = {'Average trial length (min/max)'};
            app.AvgtriallengthEditField.Value = '0 (0/0)';
            app.AvgintertriallengthEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.AvgintertriallengthEditFieldLabel.Enable = 'off';
            app.AvgintertriallengthEditFieldLabel.Text = 'Avg. inter-trial length :';
            app.AvgintertriallengthEditField = uieditfield(app.TriggerinfoPanel, 'text');
            app.AvgintertriallengthEditField.Enable = 'off';
            app.AvgintertriallengthEditField.Tooltip = {'Average inter-trial length (min/max)'};
            app.AvgintertriallengthEditField.Value = '0 (0/0)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_evntManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end