classdef DataViewer_multiPlots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiplottoolUIFigure  matlab.ui.Figure
        FileBtn_1              matlab.ui.control.Button
        EditField_1            matlab.ui.control.EditField
        FileBtn_2              matlab.ui.control.Button
        EditField_2            matlab.ui.control.EditField
        FileBtn_3              matlab.ui.control.Button
        EditField_3            matlab.ui.control.EditField
        File1Label             matlab.ui.control.Label
        File2Label             matlab.ui.control.Label
        File3Label             matlab.ui.control.Label
        icon_1                 matlab.ui.control.Image
        icon_2                 matlab.ui.control.Image
        icon_3                 matlab.ui.control.Image
        LoadButton             matlab.ui.control.Button
        SelectfilesLabel       matlab.ui.control.Label
        szLab_1                matlab.ui.control.EditField
        szLab_2                matlab.ui.control.EditField
        szLab_3                matlab.ui.control.EditField
        explanationLabel       matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Handle to "DataViewer" app.
        mainFile % Full path to the file displayed in "DataViewer".
        fileSize % Size of the data in "DataViewer". This will be used to avoid loading data with different sizes.
        fileMapArray = {}; % Cell Array of memmapfile objects pointing to the files to be opened.
        figH % Handle to mainWindow.
        imgAxArr % Array of axis handles containing the images.
        plotAx % Handle of the time plot (if applicable).
        b_hasTimePlot %(bool) True, if the DataViwer has a temporal profile figure.
    end
    
    properties (Access = public, SetObservable)
        el % event listener for changes in MainApp's time plot
    end
    
    methods (Access = private)
        
        function updateImage(app)
            % This function loads the image frames from the memory-mapped
            % data.
            sgtitle(app.figH, 'Updating images...');drawnow
            for i = 1:length(app.imgAxArr)
                imagesc(app.imgAxArr{i}, app.fileMapArray{i}.Data.data(:,:,app.MainApp.currInfo.frame));
            end
            % Update subplot group title
            sgtitle(app.figH, ['Frame # ' num2str(app.MainApp.currInfo.frame)]);
        end


        %%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%
        function updatePlot(app,~,~)
            % This function updates the time plot content from the memory-mapped
            % data and from the time plot content from MainApp.
           
            if ~isvalid(app)
                return
            end
            sgtitle(app.figH, 'Updating plots...');drawnow
            tic;
            % Calculate time plots for each image:
            h_source = findobj(app.MainApp.timeFig.axT1, 'Tag', 'sigLine');
            h_target = findobj(app.plotAx, 'Tag', 'sigLine');
            h_target.YData = h_source.YData;
            for i = 1:length(app.imgAxArr)
                h = findobj(app.plotAx, 'Tag', ['sigLine_' num2str(i)]);
                h.YData = squeeze(app.fileMapArray{i}.Data.data(app.MainApp.currInfo.sel_pT(2), app.MainApp.currInfo.sel_pT(1),:));
            end
            sgtitle(app.figH, ['Frame # ' num2str(app.MainApp.currInfo.frame)]);                       
            toc
        end
        
        function CloseAll(app,~,~)
            % Custom CloseRequest Fcn for main figure.
            delete(app.figH)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % This app selects up to 3 files to plot alongside with the
            % opened file in the "DataViewer" app.
            app.MainApp = MainApp;
            app.mainFile = app.MainApp.dataInfo.Map.Filename;
            app.fileSize = app.MainApp.dataInfo.Map.Format{2};
            app.b_hasTimePlot = strcmp(app.MainApp.timeFig.Fig.Visible, 'on');
            %
            app.explanationLabel.Text = [app.explanationLabel.Text...
                strjoin(cellfun(@num2str, num2cell(app.fileSize), 'UniformOutput',false), 'x')];


        end

        % Button pushed function: FileBtn_1, FileBtn_2, FileBtn_3
        function OpenFile_callback(app, event)
            % This callback is executed when the user clicks on one of the
            % FileBtn_x buttons.
            % There are two behaviours:
            %  1. The function checks if the file contains data with size
            %   "fileSize". If not, throws an error dialog and disables the
            %   "load" button.
            %  2. If the wrong file was chosen, the next click will erase
            %   it from the EditField.
            
            % Create list of GUI elements:
            fileBtn_list = [app.FileBtn_1, app.FileBtn_2, app.FileBtn_3];
            icon_list = [app.icon_1, app.icon_2, app.icon_3];
            editField_list = [app.EditField_1, app.EditField_2, app.EditField_3];
            label_list = [app.szLab_1, app.szLab_2, app.szLab_3];
            
            % Find index of the button clicked:
            indx = find(arrayfun(@(x) isequal(event.Source,x), [app.FileBtn_1, app.FileBtn_2, app.FileBtn_3]));
            
            if strcmp(fileBtn_list(indx).Icon, 'icon_error.png')
                editField_list(indx).Value = '';
                label_list(indx).Value = '';
                set(icon_list(indx), 'ImageSource','icon_check.png', 'Visible','off')
                fileBtn_list(indx).Icon = 'icon_WinFolder.png';
                return
            end
            [file,folder] = uigetfile(fullfile(fileparts(app.mainFile), '*.dat'), ['Choose File #' num2str(indx)]);
            if file == 0
                disp('Operation cancelled by User')
                return;
            end
            %
            set(editField_list(indx), 'Value', 'Loading...'); drawnow
            %
            fileMap = mapDatFile(fullfile(folder,file));
            filesz =['size: ' strjoin(cellfun(@num2str, num2cell(fileMap.Format{2}), 'UniformOutput',false), 'x')];
            editField_list(indx).Value = file;
            label_list(indx).Value = filesz;
            
            % Perform checks:
            b_isOK = false;
            % Check if data has the same size than the one in DataViewer:
            if ~isequal(fileMap.Format{2}, app.fileSize)
                errMsg = 'The data size of the file is different from the one in the "DataViewer" app!';
            elseif any(cellfun(@(x) strcmp(fullfile(folder,file), x.Filename), app.fileMapArray))
                errMsg = 'This file is already loaded!';
            elseif strcmp(fullfile(folder,file), app.mainFile)
                errMsg = 'This is the same file that is open in DataViewer app';
            else
                b_isOK = true;
            end
            
            if ~b_isOK
                set(icon_list(indx), 'ImageSource', 'icon_error.png', 'Visible', 'on');
                set(fileBtn_list(indx), 'Icon', 'icon_error.png');
                app.LoadButton.Enable = 'off';
                er= errordlg(errMsg, 'Invalid data size!');
                waitfor(er)
                return
            end
            
            % Save file memmap to array and display info in gui:
            app.fileMapArray = [app.fileMapArray;{fileMap}];
            set(icon_list(indx), "ImageSource", "icon_check.png", "Visible", "on");
            set(fileBtn_list(indx), "Icon", "icon_WinFolder.png", 'Enable', 'on');
            editField_list(indx).Enable = 'on';
            % Enable next file fields:
            if indx < 3
                fileBtn_list(indx+1).Enable = 'on';
                editField_list(indx+1).Enable = 'on';
            end
            app.LoadButton.Enable = 'on';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % This callback creates a figure ('mainWindow')containing the 1-3 images from
            % the chosen data. After the figure creation the main app is
            % made invisible and is deleted when the user closes the
            % 'mainWindow'.
            
            % Create figure:
            app.figH = figure('Name', 'Multiplot- Main window', 'NumberTitle','off','Color', 'w','Visible', 'on',...
                'Position',[0 0 900 360],'CloseRequestFcn',@(src,evnt) app.CloseAll(src,evnt));
            movegui(app.figH, 'center');
            % Create axes:
            switch length(app.fileMapArray)
                case 1
                    app.imgAxArr{1} = subplot(1,3,1);
                    app.plotAx = subplot(1,3,[2 3]);
                    
                case 2
                    app.imgAxArr{1} = subplot(2,2,1);
                    app.imgAxArr{2} = subplot(2,2,2);
                    app.plotAx = subplot(2,2,[3 4]);
                case 3
                    app.imgAxArr{1} = subplot(2,3,1);
                    app.imgAxArr{2} = subplot(2,3,2);
                    app.imgAxArr{3} = subplot(2,3,3);
                    app.plotAx = subplot(2,3,[4:6]);
                otherwise
                    error('File Map array has an invalid length!')
            end
            % Copy graphical elements from main time plot:
            copyobj(app.MainApp.timeFig.axT1.Children, app.plotAx);
            % Load images
            app.updateImage
            imgCols = {[0,0,0], [.2 .2 .2], [.4 .4 .4]};
            % Set image axis properties:
            for i = 1:length(app.imgAxArr)
                set(app.imgAxArr{i}, 'DataAspectRatio',[1 1 1], 'DataAspectRatioMode','manual', ...
                    'XTick','', 'YTick','','LineWidth',3, 'XColor', imgCols{i}, 'YColor', imgCols{i});
                [~,filename,~] = fileparts(app.fileMapArray{i}.Filename);
                set(app.imgAxArr{i}.Title, 'String',filename, 'Interpreter','none');
%                 axis(app.imgAxArr{i}, 'off');
            end
            %
            % Set temporal plot properties:
            %             set(app.plotAx, 'XLim', app.MainApp.timeFig.axT1.XLim, 'YLim', app.MainApp.timeFig.axT1.YLim);
            set(app.plotAx.Title, 'String', 'Temporal profile');
            % Create lines and shades in temporal plot elements:
            
            for i = 1:length(app.fileMapArray)
                h_target = copyobj(findobj(app.plotAx, 'Tag', 'sigLine', '-or', 'Tag', 'sigShade'), app.plotAx);
                h_target(1).Tag = ['sigLine_' num2str(i)]; h_target(2).Tag = ['sigShade_' num2str(i)];
                set(h_target(1), 'Color', imgCols{i});
                set(h_target(2), 'FaceColor', imgCols{i}, 'EdgeColor', imgCols{i});
            end
            % Create event listener to update time plot figure:
            app.el= addlistener(app.MainApp, 'updateMultiPlot', @app.updatePlot);
            app.updatePlot;
            % Show figure:
            app.figH.Visible = 'on';
        end

        % Close request function: MultiplottoolUIFigure
        function CloseApp(app, event)
            delete(app.figH)
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Erases the list of files.
            app.fileMapArray = {};
            for i = 1:3
                if i == 1
                    eval(['set(app.FileBtn_' num2str(i) ', "Enable", "on","Icon", "icon_WinFolder.png");'])
                else
                    eval(['set(app.FileBtn_' num2str(i) ', "Enable", "off","Icon", "icon_WinFolder.png");'])
                end
                eval(['set(app.EditField_' num2str(i) ',"Value", "...");'])
                eval(['set(app.szLab_' num2str(i) ', "Value", "");'])
                eval(['set(app.icon_' num2str(i) ', "ImageSource", "icon_check.png", "Visible", "off");'])
            end
            set(app.LoadButton, 'Enable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultiplottoolUIFigure = uifigure('Visible', 'off');
            app.MultiplottoolUIFigure.Name = 'Multi-plot tool';
            app.MultiplottoolUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.FileBtn_1 = uibutton(app.MultiplottoolUIFigure, 'push');
            app.FileBtn_1.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_callback, true);
            app.FileBtn_1.Icon = 'icon_WinFolder.png';
            app.FileBtn_1.Text = '';
            app.EditField_1 = uieditfield(app.MultiplottoolUIFigure, 'text');
            app.EditField_1.Editable = 'off';
            app.EditField_1.Value = '...';
            app.FileBtn_2 = uibutton(app.MultiplottoolUIFigure, 'push');
            app.FileBtn_2.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_callback, true);
            app.FileBtn_2.Icon = 'icon_WinFolder.png';
            app.FileBtn_2.Enable = 'off';
            app.FileBtn_2.Text = '';
            app.EditField_2 = uieditfield(app.MultiplottoolUIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '...';
            app.FileBtn_3 = uibutton(app.MultiplottoolUIFigure, 'push');
            app.FileBtn_3.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_callback, true);
            app.FileBtn_3.Icon = 'icon_WinFolder.png';
            app.FileBtn_3.Enable = 'off';
            app.FileBtn_3.Text = '';
            app.EditField_3 = uieditfield(app.MultiplottoolUIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = '...';
            app.File1Label = uilabel(app.MultiplottoolUIFigure);
            app.File1Label.Text = 'File #1';
            app.File2Label = uilabel(app.MultiplottoolUIFigure);
            app.File2Label.Text = 'File #2';
            app.File3Label = uilabel(app.MultiplottoolUIFigure);
            app.File3Label.Text = 'File #3';
            app.icon_1 = uiimage(app.MultiplottoolUIFigure);
            app.icon_1.Visible = 'off';
            app.icon_1.ImageSource = 'icon_check.png';
            app.icon_2 = uiimage(app.MultiplottoolUIFigure);
            app.icon_2.Visible = 'off';
            app.icon_2.ImageSource = 'icon_check.png';
            app.icon_3 = uiimage(app.MultiplottoolUIFigure);
            app.icon_3.Visible = 'off';
            app.icon_3.ImageSource = 'icon_check.png';
            app.LoadButton = uibutton(app.MultiplottoolUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.SelectfilesLabel = uilabel(app.MultiplottoolUIFigure);
            app.SelectfilesLabel.Text = 'Select files ';
            app.szLab_1 = uieditfield(app.MultiplottoolUIFigure, 'text');
            app.szLab_1.Editable = 'off';
            app.szLab_2 = uieditfield(app.MultiplottoolUIFigure, 'text');
            app.szLab_2.Editable = 'off';
            app.szLab_3 = uieditfield(app.MultiplottoolUIFigure, 'text');
            app.szLab_3.Editable = 'off';
            app.explanationLabel = uilabel(app.MultiplottoolUIFigure);
            app.explanationLabel.Text = 'Main data size = ';
            app.ResetButton = uibutton(app.MultiplottoolUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.MultiplottoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_multiPlots(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultiplottoolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiplottoolUIFigure)
        end
    end
end