classdef funcImag_viz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FunctionalImagingvisualizationLabel  matlab.ui.control.Label
        SelectdataDropDownLabel    matlab.ui.control.Label
        SelectdataDropDown         matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
        Slider                     matlab.ui.control.Slider
        ExportplotsButton          matlab.ui.control.Button
        PlotoptionsPanel           matlab.ui.container.Panel
        colormapDropDownLabel      matlab.ui.control.Label
        colormapDropDown           matlab.ui.control.DropDown
        minEditField               matlab.ui.control.NumericEditField
        maxLabel                   matlab.ui.control.Label
        maxEditField               matlab.ui.control.NumericEditField
        showcolorbarCheckBox       matlab.ui.control.CheckBox
        ClippingvaluesLabel        matlab.ui.control.Label
        minEditFieldLabel_2        matlab.ui.control.Label
        resetMinButton             matlab.ui.control.Button
        resetMaxButton             matlab.ui.control.Button
        clipplotCheckBox           matlab.ui.control.CheckBox
        showmousectxareasCheckBox  matlab.ui.control.CheckBox
        TextArea                   matlab.ui.control.TextArea
        PromptLabel                matlab.ui.control.Label
        EventsoptionsPanel         matlab.ui.container.Panel
        ConditionDropDownLabel     matlab.ui.control.Label
        ConditionDropDown          matlab.ui.control.DropDown
        RepetitionDropDownLabel    matlab.ui.control.Label
        RepetitionDropDown         matlab.ui.control.DropDown
        OpenROImanagerButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        my_cmap % current colormap
        atlas % Allen Brain Atlas structure
        inputObj % input object
        inputFile % input File
        expType % experiment type one of the following: {1 = 'ImgTimeSeries', 2 = 'ImgTimeSeriesByEvent', 3 = 'SeedPixCorr'}
        metaData % mmemmap file containing metaData from inputFile.
        timeFig % structure containing figure parameters.
        data % imaging data.
        mapData % memmap file containing imaging data.
        currFr_idx = 1 % current Frame index.
        currFr % current Frame.
        currPts = [1 1] % current X,Y position of mouse cursor.
        currVal % Value of currPts from currFr.
        selPx_Pts = [1 1] % coordinates of Pixel selected by clicking.
        curr_Rep = 1 % current Repetition (Case 2 only).
        curr_Cond = 1 % current Condition (Case 2 only).
        eventList % cell array with event ID (Case 2 only).
        start = false % boolean to start grabbing the mouse cursor position.
        colBar % colorbar handle.
    end
    
    methods (Access = private)
        
        function b_isOK = checkInput(app)
            % CHECKINPUT verifies if inputObj and inputFile obey the
            % necessary conditions.
            % Output: b_isOK, boolean to indicate if inputs obey all
            % conditions.
            b_isOK = false;
            if numel(app.inputObj) ~=1
                uiwait(errordlg('Only one object is accepted!', 'Error!', 'modal'))
                return
            end
            if iscell(app.inputObj)
                app.inputObj = app.inputObj{:};
            end
            file = fullfile(app.inputObj.SaveFolder, app.inputFile);
            if ~isfile(file)
                uiwait(errordlg(['Input file ' app.inputFile ' not found in ' app.inputObj.SaveFolder], 'Error!', 'modal'))
                return
            end
            mDat = mapDatFile(file);
            fields = fieldnames(mDat.Data);
            
            % Determine what type of experiment is represented by the Data:
            if numel(fields) == 1
                data_nDims = numel(mDat.Format{2});
                switch data_nDims
                    case 3 % X,Y,T
                        app.expType = 1;
                    case 4 % Event,X,Y,T
                        app.expType = 2;
                end
                b_isOK = true;
            elseif all(ismember(fields, {'AVG', 'STD'}))
                app.expType = 2;
            elseif all(ismember(fields, {'CM', 'P'}))
                app.expType = 3;
                b_isOK = true;
            else
                uiwait(errordlg('Invalid data type!', 'Error!', 'modal'))
                return
            end
            % initialize some variables:
            app.SelectdataDropDown.Items = fields;
            app.mapData = mDat;
            app.metaData = matfile(strrep(file, '.dat', '_info.mat'));
            app.PromptLabel.Text = ['File : ' file];
        end
        
        function showFrame(app, ID, overlayPlot)
            % ID = title
            % overlayPlot = cell array containing {X, Y, Marker,
            % MarkerFaceColor}.
            cmap = app.colormapDropDown.Value;
            if app.clipplotCheckBox.Value
                clims = [app.minEditField.Value app.maxEditField.Value];
                imagesc(app.UIAxes, app.currFr, clims); colormap(app.UIAxes, cmap); drawnow;
            else
                imagesc(app.UIAxes, app.currFr); colormap(app.UIAxes, cmap); drawnow;
                app.UIAxes.CLimMode = "auto";
            end
            title(app.UIAxes, ID)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.selPx_Pts(1), app.selPx_Pts(2), 'ro', "MarkerSize",4);
            for i = 1:size(overlayPlot,2)
                dataPt = overlayPlot(i,:);
                plot(app.UIAxes, dataPt{2}, dataPt{1}, 'Marker', dataPt{3}, 'MarkerFaceColor', dataPt{4});
            end
            hold(app.UIAxes,'off');
            if app.showcolorbarCheckBox.Value
                app.colBar.Visible = 'on';
            end
        end
        
        function showTemporalProfile(app, linePos,X, data, Colors, xl)
            % data = M x time . Data can be a 1-D array or a series of
            % arrays.
            % X = array with time (in seconds).
            % linePos = vertical line position in time corresponding to the
            % frame currently displayed in APP.UIAXES.
            % Colors = cell array of colors of each line ploted in the array.
            % xl = X axis label
            cla(app.timeFig.axT1);
            hold(app.timeFig.axT1, 'on')
            for i = 1:size(data,2)
                plot(app.timeFig.axT1, X, data(i,:), 'Color', Colors{i});
            end
            yl = [app.minEditField.Value app.maxEditField.Value];
            app.timeFig.axT1.YLim = yl;
            line(app.timeFig.axT1, [linePos linePos], [yl(1) yl(2)], 'Color', 'k');drawnow
            if isprop(app.metaData,'preEventTime_sec')
                event_onset = app.metaData.preEventTime_sec;
                line(app.timeFig.axT1, [event_onset event_onset], [yl(1) yl(2)], 'Color', 'r');drawnow
            end
            hold(app.timeFig.axT1, 'off')
            app.timeFig.axT1.YLabel.String = 'Signal amplitude';
            app.timeFig.axT1.XLabel.String = xl;
        end
        
        function DoNotCloseFig(~,~,~)
            
        end
        
        function in_bounds = getBounds(app)
            x_lims = get(app.UIAxes, 'XLim');
            y_lims = get(app.UIAxes, 'YLim');
            
            in_bounds = (app.currPts(1) >= x_lims(1)) && ...
                (app.currPts(1) <= x_lims(2)) && ...
                (app.currPts(2) >= y_lims(1)) && ...
                (app.currPts(2) <= y_lims(2));
        end
        
        function displayInfo(app)
            % Displays important information in APP.LABEL.
            txt = {};
            switch app.expType
                case 1
                    str = 'Image time series';
                case 2
                    str = 'Image time series split by event';
                case 3
                    str = 'Correlation Map';
            end
            txt{1} = ['Experiment type: ' str];
            txt{2} = ['Current position (X,Y) : ' num2str(app.currPts(1)) ' , ' num2str(app.currPts(2)) '                ' ...
                'Current Value : ' num2str(app.currVal)];
            txt{3} = ['Selected Pixel position (X,Y) :' num2str(app.selPx_Pts(1)) ' , ' num2str(app.selPx_Pts(2)) '       ' ...
                'Selected Pixel Value : ' num2str(app.currFr(app.selPx_Pts(2), app.selPx_Pts(1)))];
            app.TextArea.Value = txt;
        end
        
        function updateFigure(app)
            % Updates image and plot
            switch app.expType
                case {1,2}
                    app.currFr = app.data(:,:,app.currFr_idx);
                    app.showFrame(['Frame #' num2str(app.currFr_idx)], {});
                    tempData = app.data(app.selPx_Pts(2), app.selPx_Pts(1),:);
                    try
                        X = (1:length(tempData))./app.metaData.Freq;
                        linePos = X(app.currFr_idx);
                        xLab = 'time (seconds)';
                    catch
                        X = 1:length(tempData);
                        linePos = app.currFr_idx;
                        xLab = 'frame number';
                    end
                    app.showTemporalProfile(linePos, X, tempData, {'b'}, xLab)
                                  
                case 3
                    szdat = size(app.data);
                    app.currFr_idx = sub2ind(szdat([2 1]), app.selPx_Pts(1), app.selPx_Pts(2));
                    app.currFr = app.data(:,:,app.currFr_idx);
                    app.showFrame('SPCM',{});
            end
            app.Slider.Value = app.currFr_idx;
            
        end
        
        function changeFrame(app,src,~)
            ax = src.Children;
            click = get(src, 'SelectionType');
            if strcmp(click, 'open')
                coords = get(ax, 'CurrentPoint');
                x_pos = round(coords(1,1));
                if strcmp(ax.XLabel.String, 'time (seconds)')
                    x_pos = x_pos*app.metaData.Freq;
                end
                if x_pos < 1
                    x_pos = 1;
                elseif x_pos> size(app.data,3)
                    x_pos = size(app.data,3);
                end
                app.currFr_idx = x_pos;
                app.updateFigure;
                app.Slider.Value = app.currFr_idx;
            end
        end
        
        function loadData(app)
            %Load data and show images:
            dataField = app.SelectdataDropDown.Value;
            if app.expType == 1
                app.data = flipud(rot90(app.mapData.Data.(dataField)));
                idx = (sum(app.data,3) == 0);
                app.data = app.data./(~idx);
                app.timeFig.Fig.Visible = 'on';
            elseif app.expType == 2
                app.timeFig.Fig.Visible = 'on';
                app.EventsoptionsPanel.Visible = 'on';
                app.loadEvents();
                % Find condition and repetition indices:
                cond_ID = app.ConditionDropDown.Value;
                indx_cond = find(strcmp(cond_ID, app.ConditionDropDown.Items));
                indx_evID = find(app.eventList == indx_cond);
                rep_ID = app.RepetitionDropDown.Value;
                if strcmp(rep_ID, '#Average')
                    indx = indx_evID;
                else
                    indx = indx_evID(str2double(rep_ID));
                end
                % Get data snippet:
                dat = squeeze(app.mapData.Data.(dataField)(indx, :,:,:));
                if numel(size(dat))>3 && size(dat,1)>1
                    dat = squeeze(nanmean(dat,1));
                end
                idx = (sum(dat,3) == 0);
                dat = dat.*(~idx); %transform non-ROI to NaN.
                app.data = flipud(rot90(dat));
            else
                app.timeFig.Fig.Visible = 'off';
                app.Slider.Visible = 'off';
                app.data = flipud(rot90(app.mapData.Data.(dataField)));
            end
            app.Slider.Limits = [1 size(app.data,3)];
            step = round(size(app.data,3)/20);
            app.Slider.MajorTicks = 1:step:size(app.data,3);
            app.Slider.MajorTickLabels = {};
        end
        
        function loadEvents(app)
            % Saves the event list to property "eventList"
            if ~isprop(app.metaData, 'eventID')
                uiwait(errordlg('Can''t find event list in Meta Data file!', 'Error!', 'modal'))
                app.UIFigureCloseRequest;
            end
            list = app.metaData.eventNameList;
            IDs = app.metaData.eventID;  
            % transforms list items to strings:
            condList = cellfun(@(x) num2str(x), list, 'UniformOutput',false);
            n_reps = numel(IDs)/numel(unique(IDs));
            repList = arrayfun(@(x) num2str(x), (1:n_reps), 'UniformOutput', false);
            app.eventList = IDs;
            app.ConditionDropDown.Items = condList;
            app.RepetitionDropDown.Items = [repList, '#Average'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputObj, inputFile)
            
            % Initialize temporal Figure:
            app.timeFig.Fig = uifigure('Name', 'Temporal signal', 'NumberTitle','off',...
                'Position', [841 748 750 250], 'Color', 'w', 'MenuBar', 'none',...
                'ToolBar', 'none', 'Resize', 'off', 'Visible','off',...
                'CloseRequestFcn', @app.DoNotCloseFig, 'WindowButtonDownFcn', @app.changeFrame);
            %Graph:
            app.timeFig.axT1 = uiaxes(app.timeFig.Fig, 'Position', [5 5 740 240]);
            app.EventsoptionsPanel.Visible = 'off';
            app.ExportplotsButton.Enable = 'off';
            %             app.Slider.MinorTicks = [];
            root = getenv('Umitoolbox');
            file = fullfile(root, 'GUI\DataViz\mouse_ctx_borders.mat');
            a = load(file);
            app.atlas = a.atlas;
            app.my_cmap = app.colormapDropDown.Value;
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.Title.String = '';
            % Initialize parameters
            app.inputObj = inputObj;
            app.inputFile = inputFile;
            b_isOK = app.checkInput;
            if ~b_isOK
                delete(app)
            else
                app.loadData;
                % Initialize plot parameter values:
                app.UIAxes.XLim = [1 size(app.data,2)];
                app.UIAxes.YLim = [1 size(app.data,1)];
                app.minEditField.Value = double(min(app.data(:)));
                if isinf(app.minEditField.Value)
                    idx = isinf(app.data(:));
                    app.minEditField.Value = double(min(app.data(~idx)));
                end
                app.maxEditField.Value = double(max(app.data(:)));
                if isinf(app.maxEditField.Value)
                    idx = isinf(app.data(:));
                    app.maxEditField.Value = double(max(app.data(~idx)));
                end
                % Show plots and display plot infos:
                app.updateFigure;
                app.colBar = colorbar(app.UIAxes, 'eastoutside');
                if ~app.showcolorbarCheckBox.Value
                    app.colBar.Visible = 'off';
                end
                if ~app.clipplotCheckBox.Value
                    app.minEditField.Enable = 'off';
                    app.maxEditField.Enable = 'off';
                    app.resetMinButton.Enable = 'off';
                    app.resetMaxButton.Enable = 'off';
                end
                app.displayInfo;
                app.start = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.timeFig.Fig)
            delete(app)
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.start
                coords = get(app.UIAxes, 'CurrentPoint');
                app.currPts = round(coords(1,1:2));
                in_bounds = app.getBounds;
                if ~in_bounds
                    return
                else
                    app.currVal = app.currFr(app.currPts(2), app.currPts(1));
                    app.displayInfo;
                end
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            click = get(app.UIFigure, 'SelectionType');
            in_bounds = app.getBounds;
            if ~in_bounds
                return
            end
            if strcmp(click, 'normal')
                app.selPx_Pts = app.currPts;
                app.updateFigure;
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.currFr_idx = round(event.Value);
            app.updateFigure
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            app.updateFigure;
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
           app.updateFigure;
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
           app.updateFigure;
        end

        % Button pushed function: resetMinButton
        function resetMinButtonPushed(app, event)
            app.minEditField.Value = double(min(app.data(:)));
            if isinf(app.minEditField.Value)
                idx = isinf(app.data(:));
                app.minEditField.Value = double(min(app.data(~idx)));
            end
            app.updateFigure;
        end

        % Button pushed function: resetMaxButton
        function resetMaxButtonPushed(app, event)
            app.maxEditField.Value = double(max(app.data(:)));
            if isinf(app.maxEditField.Value)
                idx = isinf(app.data(:));
                app.maxEditField.Value = double(max(app.data(~idx)));
            end
            app.updateFigure;
        end

        % Value changed function: showcolorbarCheckBox
        function showcolorbarCheckBoxValueChanged(app, event)
            value = app.showcolorbarCheckBox.Value;
            if value
                app.colBar.Visible = 'on';
            else
                app.colBar.Visible = 'off';
            end
        end

        % Value changed function: clipplotCheckBox
        function clipplotCheckBoxValueChanged(app, event)
            value = app.clipplotCheckBox.Value;
            if value
                app.minEditField.Enable = 'on';
                app.maxEditField.Enable = 'on';
                app.resetMinButton.Enable = 'on';
                app.resetMaxButton.Enable = 'on';
            else
                app.minEditField.Enable = 'off';
                app.maxEditField.Enable = 'off';
                app.resetMinButton.Enable = 'off';
                app.resetMaxButton.Enable = 'off';
            end
        end

        % Value changed function: SelectdataDropDown
        function SelectdataDropDownValueChanged(app, event)
            app.loadData;
            app.updateFigure;
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            app.loadData;
            app.updateFigure;
        end

        % Value changed function: RepetitionDropDown
        function RepetitionDropDownValueChanged(app, event)
            app.loadData;
            app.updateFigure;
        end

        % Button pushed function: ExportplotsButton
        function ExportplotsButtonPushed(app, event)
            folder = app.inputObj.SaveFolder;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.FunctionalImagingvisualizationLabel = uilabel(app.UIFigure);
            app.FunctionalImagingvisualizationLabel.Text = 'Functional Imaging visualization';
            app.SelectdataDropDownLabel = uilabel(app.UIFigure);
            app.SelectdataDropDownLabel.Text = 'Select data';
            app.SelectdataDropDown = uidropdown(app.UIFigure);
            app.SelectdataDropDown.Items = {'data'};
            app.SelectdataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectdataDropDownValueChanged, true);
            app.SelectdataDropDown.Value = 'data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ExportplotsButton = uibutton(app.UIFigure, 'push');
            app.ExportplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportplotsButtonPushed, true);
            app.ExportplotsButton.Text = 'Export plots';
            app.PlotoptionsPanel = uipanel(app.UIFigure);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.colormapDropDownLabel = uilabel(app.PlotoptionsPanel);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.PlotoptionsPanel);
            app.colormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'parula';
            app.minEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxLabel = uilabel(app.PlotoptionsPanel);
            app.maxLabel.Text = 'max';
            app.maxEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.showcolorbarCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.showcolorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @showcolorbarCheckBoxValueChanged, true);
            app.showcolorbarCheckBox.Text = 'show colorbar';
            app.ClippingvaluesLabel = uilabel(app.PlotoptionsPanel);
            app.ClippingvaluesLabel.Text = 'Clipping values:';
            app.minEditFieldLabel_2 = uilabel(app.PlotoptionsPanel);
            app.minEditFieldLabel_2.Text = 'min';
            app.resetMinButton = uibutton(app.PlotoptionsPanel, 'push');
            app.resetMinButton.ButtonPushedFcn = createCallbackFcn(app, @resetMinButtonPushed, true);
            app.resetMinButton.Icon = 'icon_back_arrow.png';
            app.resetMinButton.Text = '';
            app.resetMaxButton = uibutton(app.PlotoptionsPanel, 'push');
            app.resetMaxButton.ButtonPushedFcn = createCallbackFcn(app, @resetMaxButtonPushed, true);
            app.resetMaxButton.Icon = 'icon_back_arrow.png';
            app.resetMaxButton.Text = '';
            app.clipplotCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.clipplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @clipplotCheckBoxValueChanged, true);
            app.clipplotCheckBox.Text = 'clip plot';
            app.showmousectxareasCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.showmousectxareasCheckBox.Enable = 'off';
            app.showmousectxareasCheckBox.Tooltip = {'overlays the image with the borders of the mouse cortical areas. Bregma and Lambda positions are necessary for this to work.'};
            app.showmousectxareasCheckBox.Text = {'show mouse ctx.'; ' areas'};
            app.TextArea = uitextarea(app.UIFigure);
            app.PromptLabel = uilabel(app.UIFigure);
            app.PromptLabel.Text = 'Prompt:';
            app.EventsoptionsPanel = uipanel(app.UIFigure);
            app.EventsoptionsPanel.Title = 'Events options';
            app.ConditionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.EventsoptionsPanel);
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.RepetitionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.RepetitionDropDownLabel.Text = 'Repetition';
            app.RepetitionDropDown = uidropdown(app.EventsoptionsPanel);
            app.RepetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @RepetitionDropDownValueChanged, true);
            app.OpenROImanagerButton = uibutton(app.UIFigure, 'push');
            app.OpenROImanagerButton.Enable = 'off';
            app.OpenROImanagerButton.Text = 'Open ROI manager';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = funcImag_viz(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end