classdef retino_maps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RetinotopySpectrumLabel  matlab.ui.control.Label
        OpenButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        FrameSlider              matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        powMov % power Movie
        phiMov % angle Movie
        DeltaFMov % DeltaF movie
        freq = 2 % frequency index chosen by user in fft plot
        start = false % boolean to start picking up the current cursor position.
        currentPt  = [1 1]% current point clicked with mouse.
        fftData % 1-D FFT spectrum used in UIAxes_3
    end
    
    methods (Access = private)
        
        function UpdateFFTplot(app)
            app.fftData = squeeze(app.powMov(app.currentPt(1), app.currentPt(2), :));
            plot(app.UIAxes_3, app.fftData, 'o-', 'Color', [.4 .4 .4], 'MarkerSize',3, 'MarkerFaceColor',[.4 .4 .4]);
            hold(app.UIAxes_3, 'on')
            plot(app.UIAxes_3, app.freq, app.fftData(app.freq), 'ro', 'MarkerSize',10);
            hold(app.UIAxes_3, 'off');
        end
        
        function UpdateImages(app)
            powFr = abs(app.powMov(:,:,app.freq));
            phiFr = -1*app.phiMov(:,:,app.freq);
            phiFr = mod(phiFr, 2*pi);
            imagesc(app.UIAxes, powFr, [0 max(app.powMov(:))]); colormap(app.UIAxes, 'hot');
            hold(app.UIAxes, 'on');
            line(app.UIAxes, [app.UIAxes.XLim(1) app.UIAxes.XLim(2)], [app.currentPt(2) app.currentPt(2)], 'Color', 'white');
            line(app.UIAxes, [app.currentPt(1) app.currentPt(1)], [app.UIAxes.YLim(1) app.UIAxes.YLim(2)], 'Color', 'white');
            hold(app.UIAxes, 'off');
            imagesc(app.UIAxes_2, phiFr); colormap(app.UIAxes_2, 'hsv');
        end
        
        
        function in_bounds = getBounds(~, ax, currentPt)
            x_lims = get(ax, 'XLim');
            y_lims = get(ax, 'YLim');
            
            in_bounds = (currentPt(1) >= x_lims(1)) && ...
                (currentPt(1) <= x_lims(2)) && ...
                (currentPt(2) >= y_lims(1)) && ...
                (currentPt(2) <= y_lims(2));
        end
        
        function updateCurrentCoords(app)
            coords = get(app.UIFigure, 'CurrentPoint');
            app.CurrentCoords = round(coords(1,1:2));
          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Title.String = 'power Map';
            app.UIAxes.DataAspectRatio = [1 1 1];
            
            app.UIAxes_2.Title.String = 'phase Map';
            app.UIAxes_2.DataAspectRatio = [1 1 1];
            
            app.UIAxes_3.Title.String = 'pixel FFT power plot';
            app.UIAxes_3.XLabel.String = 'Frequency (samples)';
            app.UIAxes_3.YLabel.String = 'power (A.U)';
            app.UIAxes_4.Title.String = '\DeltaF Frames';
            app.UIAxes_4.DataAspectRatio = [1 1 1];
            %
            app.FrameSlider.Value = 1;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, path] = uigetfile('phase_Maps.dat');
            filepath = fullfile(path, filename);
            app.Label_2.Text  = ['File : ' filepath];
            mData = mapDatFile(filepath);
            app.powMov = mData.Data.powMov;
            app.phiMov = mData.Data.phiMov;
            app.DeltaFMov = mData.Data.DeltaFMov;
            % Set axes limits
            app.UIAxes.XLim = [1 size(app.powMov,1)]; app.UIAxes.YLim = [1 size(app.powMov,2)];
            app.UIAxes_2.XLim = [1 size(app.powMov,1)]; app.UIAxes_2.YLim = [1 size(app.powMov,2)];
            app.UIAxes_3.XLim = [0 10];
            app.UIAxes_4.XLim = [1 size(app.powMov,1)]; app.UIAxes_4.YLim = [1 size(app.powMov,2)];
            % update FFtplot:
            app.UpdateFFTplot;
            % update images:
            app.UpdateImages;
            % display first frame of DeltaF Movie:
            fr = app.FrameSlider.Value;
            imagesc(app.UIAxes_4, squeeze(app.DeltaFMov(:,:,fr)), [0 max(app.DeltaFMov(:))]); colormap(app.UIAxes_4, 'gray');
            % update FrameSlider params:
            app.FrameSlider.Limits = [1 size(app.DeltaFMov,3)];
            app.FrameSlider.MajorTicks = [app.FrameSlider.Limits(1):10:app.FrameSlider.Limits(2)];
            app.FrameSlider.MajorTickLabels = {};
            app.FrameSlider.MinorTicks = [];
            app.start = true;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            currentPt_img = get(app.UIAxes, 'CurrentPoint');
            currentPt_img = round(currentPt_img(1,[1 2]));
            currentPt_plot = get(app.UIAxes_3, 'CurrentPoint');
            currentPt_plot = round(currentPt_plot(1,[1 2]));
            in_bounds_img = app.getBounds(app.UIAxes, currentPt_img);
            in_bounds_plot = app.getBounds(app.UIAxes_3, currentPt_plot);
            if in_bounds_img
                app.currentPt = currentPt_img;
            elseif in_bounds_plot
                app.freq = currentPt_plot(1);
            end
            app.UpdateImages;
            app.UpdateFFTplot;
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            fr = round(changingValue);
            disp(round(fr))
            imagesc(app.UIAxes_4, squeeze(app.DeltaFMov(:,:,fr)), [0 max(app.DeltaFMov(:))]); colormap(app.UIAxes_4, 'gray');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.RetinotopySpectrumLabel = uilabel(app.UIFigure);
            app.RetinotopySpectrumLabel.Text = 'Retinotopy Spectrum';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'* Click on a pixel from the upper left image to update fft plot.'; '** Click on a point in fft plot to update images.'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retino_maps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end