classdef MergeRecs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MergeRecordingtoolUIFigure      matlab.ui.Figure
        Tree                            matlab.ui.container.Tree
        ChooseacquisitionsListBoxLabel  matlab.ui.control.Label
        ChooseacquisitionsListBox       matlab.ui.control.ListBox
        ChooseModalityListBoxLabel      matlab.ui.control.Label
        ChooseModalityListBox           matlab.ui.control.ListBox
        ChooseFilesListBoxLabel         matlab.ui.control.Label
        ChooseFilesListBox              matlab.ui.control.ListBox
        SelectButton                    matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        SetorderListBoxLabel            matlab.ui.control.Label
        SetorderListBox                 matlab.ui.control.ListBox
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        MergetypeButtonGroup            matlab.ui.container.ButtonGroup
        MovieButton                     matlab.ui.control.RadioButton
        TrialButton                     matlab.ui.control.RadioButton
        EventsPanel                     matlab.ui.container.Panel
        StimIDEditField                 matlab.ui.control.EditField
        SetevntIDButton                 matlab.ui.control.Button
        UsecustomeventsCheckBox         matlab.ui.control.CheckBox
        NewAcquistionNameEditFieldLabel  matlab.ui.control.Label
        NewAcquistionNameEditField      matlab.ui.control.EditField
        MergeDataButton                 matlab.ui.control.Button
        NewFileNameEditFieldLabel       matlab.ui.control.Label
        NewFileNameEditField            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MainApp % Handle to umIToolbox app
        nodeLevels % list of level indice of Nodes in TREE.
        nodeList % List of selected nodes   
        fileList = {}; % List of full paths of all .dat file from the selected modality folders. This is updated in "ChooseModalityListBox" callback
    end
    
    methods (Access = private)
        
        function NodeObj = getNodeObj(app,Node, lvl)
            % GETNODEOBJ retrieves the object associated with the selected node
            % in a UITree.
            % Input:
            % node = handle to the selected UITREENODE.
            % lvl = level of the node (1 = subject; 2 =
            % acquisition; 3 = modality).
            % Outputs:
            % NodeObj = handle of the Protocol object associated with the
            % UITREE node.
            
            switch lvl
                case 1
                    idx = app.MainApp.ProtocolObj.Array.findElement('ID', Node.Text, 'strcmp');
                    NodeObj = app.MainApp.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.MainApp.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Text, 'strcmp');
                    NodeObj = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.MainApp.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                    idxA = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', Node.Text, 'strcmp');
                    NodeObj = app.MainApp.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
        end
        
        function setPanelContent(app)
            % This method populates the content of the uipanel based on the
            % selection of the items in the list boxes:
            
            app.SetorderListBox.Items = app.ChooseacquisitionsListBox.Items;
            app.MergedfilenameListBox = app.ChooseModalityListBox.Value;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.Panel.Visible = 'off';
            app.MergeRecordingtoolUIFigure.Visible = 'off';
            movegui(app.MergeRecordingtoolUIFigure, "center");
            app.MainApp = MainApp;
            % Copy UITree from main app:
            copyobj(app.MainApp.Tree.Children, app.Tree, 'legacy')
            expand(app.Tree);
            % Make figure visible:
            app.MergeRecordingtoolUIFigure.Visible = 'on';
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.Tree.Enable = 'off';drawnow
            app.SelectButton.Enable = 'off';
            if isempty(app.Tree.SelectedNodes)
                app.ChooseFilesListBox.Items = {};
                app.ChooseModalityListBox.Items ={};
                app.ChooseacquisitionsListBox.Items = {};
                app.Tree.Enable = 'on';
                return
            end
            Nodes = [];
            for i = 1:numel(app.Tree.SelectedNodes)
                if isa(app.Tree.SelectedNodes(i).Parent, 'matlab.ui.container.Tree')
                    Nodes = [Nodes;app.Tree.SelectedNodes(i).Children];
                else
                    Nodes = [Nodes;app.Tree.SelectedNodes(i)];
                end
            end
            app.nodeList = [];
            
            for i = 1:numel(Nodes)
                node = Nodes(i);
                try
                    app.nodeList = [app.nodeList;node;node.Children;[node.Children.Children]'];
                catch
                    try
                        app.nodeList = [app.nodeList;node;node.Children];
                    catch
                        app.nodeList = [app.nodeList;node];
                    end
                end
                
            end
            app.nodeList= unique(app.nodeList, 'stable');
            % Get level of each node:
            app.nodeLevels = ones(size(app.nodeList));
            for i= 1:length(app.nodeList)
                node = app.nodeList(i);
                idx = true;
                while idx
                    myParent = node.Parent;
                    if isa(myParent.Parent, 'matlab.ui.container.Tree')
                        idx = false;
                    else
                        app.nodeLevels(i) = app.nodeLevels(i) + 1;
                        node = node.Parent;
                    end
                end
            end
            % Select branches:
            app.Tree.SelectedNodes = unique(app.nodeList);
            % Erase modality and File List boxes:
            app.ChooseFilesListBox.Items = {};
            app.ChooseModalityListBox.Items ={};
            % Update acquisition list with all acquisition names that exist
            % in all selected subjects:
            indxM = find(app.nodeLevels == 3);
            myParents = [];
            for i = 1:length(indxM)
                myParents = [myParents; [string(app.nodeList(indxM(i)).Parent.Parent.Text) string(app.nodeList(indxM(i)).Parent.Text)]];
            end
            subjs = unique(myParents(:,1));
            acqs = unique(myParents(:,2));
            idxAll = false(size(subjs));
            for i = 1:numel(acqs)
                idxA = myParents(:,2) == acqs(i);
                if all(ismember(subjs, myParents(idxA,1)))
                    idxAll(i) = true;
                end
            end
            str = convertStringsToChars(acqs(idxAll));
            if ~iscell(str)
                str = {str};
            end
            app.ChooseacquisitionsListBox.Items = str;
            app.ChooseacquisitionsListBox.Value = {};
            % Expand nodes and re-enable UITREE:
            arrayfun(@expand, app.Tree.SelectedNodes)
            app.Tree.Enable = 'on';drawnow
        end

        % Value changed function: ChooseacquisitionsListBox
        function ChooseacquisitionsListBoxValueChanged(app, event)
            app.SelectButton.Enable ='off'
            app.ChooseModalityListBox.Items = {};
            app.ChooseFilesListBox.Items = {};                       
            acqList = app.ChooseacquisitionsListBox.Value;           
            if isempty(acqList)
                return
            end
            modStr = [];
            for i = 1:length(acqList)
                indxA = find(strcmp(acqList{i}, {app.nodeList.Text})' & app.nodeLevels == 2);                
                for k = 1:length(indxA)
                    modStr= [modStr;string({app.nodeList(ismember(app.nodeList, app.nodeList(indxA(k)).Children)).Text})];
                end                                              
            end
            modStr = convertStringsToChars(modStr);
            if ~iscell(modStr)
                modStr = {modStr};
            end
            app.ChooseModalityListBox.Items = unique(modStr);
            app.ChooseModalityListBox.Value = {};                       
        end

        % Value changed function: ChooseModalityListBox
        function ChooseModalityListBoxValueChanged(app, event)
            % This callback looks for all .dat files inside the chosen
            % modality folder and updates the Files ListBox
            app.ChooseFilesListBox.Items = {'Loading files...'};            
            %
            modName = app.ChooseModalityListBox.Value;
            if isempty(modName)
                app.ChooseFilesListBox.Items = {};
                return               
            end
            idxM = find(strcmp(modName, {app.nodeList.Text})' & app.nodeLevels == 3);
            % Get the input file full paths from each modality
            datList = cell(size(idxM));
            
            for i = 1:length(idxM)
                % Get protocol object from Tree node:
                nodeObj = app.getNodeObj(app.nodeList(idxM(i)), 3);                
                % Get list of .dat files that also have a meta data .mat
                % file:
                datFiles = dir(fullfile(nodeObj.SaveFolder,'*.dat'));
                matFiles = dir(fullfile(nodeObj.SaveFolder,'*.mat'));                
                idxDat = ismember(erase({datFiles.name}, '.dat'), erase({matFiles.name},'.mat'));
                % Save list to cell array:
                datList{i} = datFiles(idxDat);                
            end
            % Keep files that exist in all folders:
            datNameList = cellfun(@(x) {x.name}, datList, 'UniformOutput',false);
            datNames = unique([datNameList{:}]);            
            idxAll = false(size(datNames));
            for i = 1:length(datNames)                
                idxAll(i) = all(cellfun(@(x) any(strcmp(datNames{i},x)), datNameList, 'UniformOutput',true));
            end
            datNames = datNames(idxAll);           
            for i = 1:length(datList)
                for j = 1:length(datNames)
                    idx = strcmp(datNames{j}, {datList{i}.name});
                    app.fileList = [app.fileList; {fullfile(datList{i}(idx).folder, datList{i}(idx).name)}];
                end                   
            end                          
            app.ChooseFilesListBox.Items = datNames;                       
        end

        % Value changed function: SelectButton
        function SelectButtonValueChanged(app, event)
            
            if app.SelectButton.Value
                app.SelectButton.Text = 'Undo';
                app.Tree.Enable = 'off';
                app.ChooseacquisitionsListBox.Enable = 'off';
                app.ChooseModalityListBox.Enable = 'off';
                app.ChooseFilesListBox.Enable = 'off';
                app.Panel.Visible = 'on';              
                % Populate list boxes in uipanel:
                acqItems= app.ChooseacquisitionsListBox.Value;
                
                if ischar(acqItems); acqItems = {acqItems};end                
                app.SetorderListBox.Items = acqItems;
                app.NewFileNameEditField.Value = app.ChooseFilesListBox.Value;
                
            else
                app.SelectButton.Text = 'Select';
                app.SelectButton.Enable = 'off';
                app.Tree.Enable = 'on';
                app.ChooseacquisitionsListBox.Enable = 'on';
                app.ChooseModalityListBox.Enable = 'on';
                app.ChooseFilesListBox.Enable = 'on';
                app.Panel.Visible = 'off';
            end
            
        end

        % Value changed function: ChooseFilesListBox
        function ChooseFilesListBoxValueChanged(app, event)
            app.SelectButton.Enable = 'on';                       
        end

        % Button pushed function: Button
        function ButtonUpPushed(app, event)
              Item_selected = app.SetorderListBox.Value;
            if~isempty(Item_selected)
                Items = app.SetorderListBox.Items;                
                pos = find(strcmp(Item_selected, app.SetorderListBox.Items));                
                if pos > 1
                    range = 1:numel(Items);
                    range(pos) = pos -1 ;
                    range(pos -1) = pos;
                    app.SetorderListBox.Items = Items(range);                    
                end
            end
        end

        % Button pushed function: Button_2
        function ButtonDownPushed(app, event)
             Item_selected = app.SetorderListBox.Value;
            if~isempty(Item_selected)
                Items = app.SetorderListBox.Items;                
                pos = find(strcmp(Item_selected, app.SetorderListBox.Items)); 
                if pos < numel(Items)
                    range = 1:numel(Items);
                    range(pos) = pos + 1;
                    range(pos + 1) = pos;
                    app.SetorderListBox.Items = Items(range);                    
                end
            end
        end

        % Callback function
        function ChangenameButtonPushed(app, event)
            
            item = app.MergedfilenameListBox.Value;
            if isempty(item)
                return
            end
            idx_item = strcmp(item, app.MergedfilenameListBox.ItemsData);
            currName = app.MergedfilenameListBox.Items(idx_item);            
            newName = inputdlg('New file name', ['Rename ' item], [1 50], currName);
            if isempty(newName)
                disp('Operation cancelled by User');
                return
            elseif isempty(newName{:})
                uialert(app.MergeRecordingtoolUIFigure, 'Cannot set empty string as file name', 'Invalid Name', 'Icon', "warning");
                return
            end
            newName = newName{:};
            if ~endsWith(newName, '.dat')
                newName = [newName '.dat'];
            end           
            app.MergedfilenameListBox.Value = {};
            app.MergedfilenameListBox.Items{idx_item} = newName;
            
        end

        % Value changed function: NewFileNameEditField
        function NewFileNameEditFieldValueChanged(app, event)
            value = app.NewFileNameEditField.Value;
            if isempty(strip(value)) || strcmpi(strip(value), '.dat')
                app.NewFileNameEditField.Value = event.PreviousValue;                
                return
            end
            if ~endsWith(value, '.dat')
                value = [strip(value), '.dat'];
            end
            app.NewFileNameEditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MergeRecordingtoolUIFigure = uifigure('Visible', 'off');
            app.MergeRecordingtoolUIFigure.Name = 'MergeRecording tool';
            app.Tree = uitree(app.MergeRecordingtoolUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ChooseacquisitionsListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseacquisitionsListBoxLabel.Text = 'Choose acquisitions';
            app.ChooseacquisitionsListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseacquisitionsListBox.Items = {};
            app.ChooseacquisitionsListBox.Multiselect = 'on';
            app.ChooseacquisitionsListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseacquisitionsListBoxValueChanged, true);
            app.ChooseacquisitionsListBox.Value = {};
            app.ChooseModalityListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseModalityListBoxLabel.Text = 'Choose Modality';
            app.ChooseModalityListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseModalityListBox.Items = {};
            app.ChooseModalityListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseModalityListBoxValueChanged, true);
            app.ChooseModalityListBox.Value = {};
            app.ChooseFilesListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseFilesListBoxLabel.Text = 'Choose File(s)';
            app.ChooseFilesListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseFilesListBox.Items = {};
            app.ChooseFilesListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseFilesListBoxValueChanged, true);
            app.ChooseFilesListBox.Value = {};
            app.SelectButton = uibutton(app.MergeRecordingtoolUIFigure, 'state');
            app.SelectButton.ValueChangedFcn = createCallbackFcn(app, @SelectButtonValueChanged, true);
            app.SelectButton.Text = 'Select';
            app.Panel = uipanel(app.MergeRecordingtoolUIFigure);
            app.SetorderListBoxLabel = uilabel(app.Panel);
            app.SetorderListBoxLabel.Text = 'Set order';
            app.SetorderListBox = uilistbox(app.Panel);
            app.SetorderListBox.Items = {};
            app.SetorderListBox.Value = {};
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.Button.Icon = 'icon_arrow_up.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.Button_2.Icon = 'icon_arrow_dwn.png';
            app.Button_2.Text = '';
            app.MergetypeButtonGroup = uibuttongroup(app.Panel);
            app.MergetypeButtonGroup.Title = 'Merge type';
            app.MovieButton = uiradiobutton(app.MergetypeButtonGroup);
            app.MovieButton.Text = 'Movie';
            app.MovieButton.Value = true;
            app.TrialButton = uiradiobutton(app.MergetypeButtonGroup);
            app.TrialButton.Text = 'Trial';
            app.EventsPanel = uipanel(app.Panel);
            app.EventsPanel.Title = 'Events';
            app.StimIDEditField = uieditfield(app.EventsPanel, 'text');
            app.StimIDEditField.Editable = 'off';
            app.StimIDEditField.Enable = 'off';
            app.SetevntIDButton = uibutton(app.EventsPanel, 'push');
            app.SetevntIDButton.Enable = 'off';
            app.SetevntIDButton.Text = {'Set '; 'evnt. ID'};
            app.UsecustomeventsCheckBox = uicheckbox(app.EventsPanel);
            app.UsecustomeventsCheckBox.Text = ' Use custom events';
            app.NewAcquistionNameEditFieldLabel = uilabel(app.Panel);
            app.NewAcquistionNameEditFieldLabel.Text = 'New Acquistion Name';
            app.NewAcquistionNameEditField = uieditfield(app.Panel, 'text');
            app.NewAcquistionNameEditField.Value = 'NewAcq';
            app.MergeDataButton = uibutton(app.Panel, 'push');
            app.MergeDataButton.Text = 'Merge Data';
            app.NewFileNameEditFieldLabel = uilabel(app.Panel);
            app.NewFileNameEditFieldLabel.Text = 'New File Name';
            app.NewFileNameEditField = uieditfield(app.Panel, 'text');
            app.NewFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewFileNameEditFieldValueChanged, true);
            app.NewFileNameEditField.Value = 'newfile.dat';
            % Show the figure after all components are created
            app.MergeRecordingtoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergeRecs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MergeRecordingtoolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MergeRecordingtoolUIFigure)
        end
    end
end