classdef PipelineConfig_dlgBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        AvailablefunctionsPanel    matlab.ui.container.Panel
        AvailablefunctionsListBox  matlab.ui.control.ListBox
        AddButton                  matlab.ui.control.Button
        HelpButton                 matlab.ui.control.StateButton
        ForceAddButton             matlab.ui.control.Button
        GridLayout3                matlab.ui.container.GridLayout
        RunButton                  matlab.ui.control.Button
        saveButton                 matlab.ui.control.Button
        openButton                 matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        pipeObj % Instantiation of PipelineManager class.
        b_isStandAlone % True if calling app is DataViewer StandAlone version.
        b_OpenDoc = false; % If true, the valuechanged callback of the "Available functions" list is executed. This is controlled by the state of the "Help" button.
        avFcnTooltip % Default tooltip for "Available" functions list.
        mainApp % Calling app.
        pipeFig % Handle to pipeline figure.
        btnState % "Enable" state of UI buttons. Used by the Help Btn callback.
        T1 % Timer object for "Restore" button.
        T2 % Timer object for "ForceAdd" button.
    end
    
    methods (Access = private)
        function createFuncList(app)
            % This function populates the "AvailablefunctionsListBox" with
            % the functions listed in "pipeObj".
            
            Items = {app.pipeObj.funcList.name};
            ItemsData = app.pipeObj.funcList;
            list_of_folders = cellfun(@(x) erase(x, app.pipeObj.fcnDir), {app.pipeObj.funcList.folder}, 'UniformOutput',false);
            if app.b_isStandAlone
                idx = contains(list_of_folders, 'DataImport') | contains(list_of_folders, 'DataExport'); % Remove all DataImport or DataExport options for the StandAlone version. The GUI has separate buttons for that!
                list_of_folders(idx) = [];
                Items(idx) = [];
                ItemsData(idx) = [];
            end
            idx = arrayfun(@(x) ~isempty(x.info.opts), ItemsData);
            Items(idx) = cellfun(@(x) ['(!) ' x ], Items(idx), 'UniformOutput',false);
            [val,ind] = unique(list_of_folders);
            new_Items = {};
            new_ItemsData = [];
            for i = 1:numel(ind)
                header =['---------- ' val{i} ' ----------'];
                dummyData = ItemsData(1); dummyData.name = '----------';
                if i < numel(ind)
                    sub_Items = Items(ind(i):ind(i+1)-1);
                    sub_ItemsData = ItemsData(ind(i):ind(i+1)-1);
                else
                    sub_Items = Items(ind(i):end);
                    sub_ItemsData = ItemsData(ind(i):end);
                end
                new_Items = [new_Items, header, sub_Items];
                new_ItemsData = [new_ItemsData; dummyData; sub_ItemsData];
            end
            app.AvailablefunctionsListBox.Items = new_Items;
            app.AvailablefunctionsListBox.ItemsData = new_ItemsData;
        end
        
        function b_ok = createPipe(app)
            % This function uses the content of the
            % SelectedfunctionsListBox to add each function to the
            % pipeObj's pipeline.
            % Output:
            %   b_ok (bool): If TRUE, all steps were added to the pipeline.
            %   If FALSE, an error occured or User decided to abort.
            % Erase existing pipeline:
            b_ok = false;
            if ~isempty(app.pipeObj)
                app.pipeObj.reset_pipe;
            end
            disp('Creating pipeline...')
            % Create pipeline
%             ItemsData = app.MypipelineListBox.ItemsData;
%             ItemsData(1).seq = 0;
%             ItemsData(1).seqIndx = 0;
%             curr_seq = 1;
%             for ii = 1:numel(ItemsData)
%                 % Check for exising steps:
%                 currPipe = app.pipeObj.pipe(arrayfun(@(x) any(x.seq == curr_seq), app.pipeObj.pipe));
%                 if any(strcmpi(ItemsData(ii).name, {currPipe.name}))
%                     % If there is an existing function, force the creation
%                     % of a new branch:
%                     state = app.pipeObj.addTask(ItemsData(ii).name, ItemsData(ii).b_save, ItemsData(ii).saveFileName, 'inputFrom','_LOCAL_');
%                 end
%                 % Add item to new pipeline sequence:
%                 state = app.pipeObj.addTask(ItemsData(ii).name, ItemsData(ii).b_save, ItemsData(ii).saveFileName);
%                 %
%                 curr_seq = max([app.pipeObj.pipe.seq], [],'all');
%                 % If failed to add a step to the pipeline, reset and
%                 % return.
%                 if ~state
%                     app.pipeObj.reset_pipe;
%                     return
%                 end
%             end
            b_ok = true;
        end
        
        
        function DoNothing(~,~,~)
            % Dummy callback to avoid closing figures.
        end
        
        function  updatePipeFig(app)
            % This function creates / updates the pipeline figure.
            
            if isempty(app.pipeObj.pipe)
                return
            end
            % Create the figure with the pipeline:
            if isempty(app.pipeFig)
                app.pipeFig = figure('Name','Pipeline workflow','NumberTitle',"off","MenuBar","none","ToolBar","none", 'CloseRequestFcn',@app.DoNothing);
                % Relocate figure at the right side of the app
                app.pipeFig.Position([1 2]) = [sum(app.UIFigure.Position([1 3]))+10, app.pipeFig.Position(2) + sum(app.UIFigure.Position([2 4])) - sum(app.pipeFig.Position([2 4]))];
            end
            % Draw pipeline workflow
            app.pipeObj.drawPipe(app.pipeFig);
            % Enable "Run" button:
            app.RunButton.Enable = 'on';
            % Enable "save" button
            app.saveButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, pipeObj, b_isStandAlone)
            
            % Update UIFigure position:
            app.UIFigure.Visible ='off';
            movegui(app.UIFigure, 'center');
            app.mainApp = mainApp;
            app.pipeObj = pipeObj;
            app.b_isStandAlone = b_isStandAlone;
            app.createFuncList;
            app.updatePipeFig;
            %%
            app.UIFigure.Visible ='on';
            app.avFcnTooltip = app.AvailablefunctionsListBox.Tooltip;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
  
            delete(app.pipeFig);
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Adds the selected function from the "Available" to the
            % pipeline.
            FuncSel = app.AvailablefunctionsListBox.Value;
            if isempty(FuncSel) || strcmpi(FuncSel.name, '----------')
                return
            end
            idx = strcmp({app.AvailablefunctionsListBox.ItemsData.name}, FuncSel.name);
            newItemData = app.AvailablefunctionsListBox.ItemsData(idx);
            b_hasDuplicates = false;
            if ~isempty(app.pipeObj.pipe)
                % Check for duplicates in current sequence
                b_hasDuplicates = any(strcmpi(newItemData.name, {app.pipeObj.pipe(arrayfun(@(x) any(x.seq == app.pipeObj.current_seq), app.pipeObj.pipe)).name}));
                % Check if the function has any "data" as output:
                if ~newItemData.info.b_hasDataOut
                    return
                end
            end
            % Add pertinent fields to newItemData:
            newItemData.inputFrom = '';
            newItemData.inputFileName = '';
            if b_hasDuplicates
                answer = uiconfirm(app.UIFigure, sprintf('The function "%s" already exists in the current sequence of the pipeline. Force creation of a new branch?',newItemData.name),...
                    'Function already added!', 'Options',{'Yes','No'}, 'DefaultOption',2);
                if ~strcmpi(answer,'yes')
                    %%%%% TBD
                    % Point to "force Add" callback"
                    %%%%% TBD
                    return
                end
                
            end
            
            state = app.pipeObj.addTask(newItemData.name, 'inputFrom',newItemData.inputFrom, 'inputFileName', newItemData.inputFileName);
            if ~state
                uialert(app.UIFigure, ['Failed to add "' newItemData.name '" to the pipeline!'], 'Operation failed!','Icon', 'error', "CloseFcn",'uiresume(gcbf)')
                uiwait(app.UIFigure);
                return
            end
            % Draw pipeline
            app.updatePipeFig;
            % Bring scope back to figure:
            figure(app.UIFigure)
        end

        % Button pushed function: ForceAddButton
        function ForceAddButtonPushed(app, event)
            % Force input to the selected function.
            
            FuncSel = app.AvailablefunctionsListBox.Value;
            if isempty(FuncSel) || strcmpi(FuncSel.name, '----------') || isempty(app.pipeFig)
                return
            end
            if isempty(app.ForceAddButton.Text) && ( isempty(app.T2) || ~isvalid(app.T2) )
                % Change button to ask for confirmation:
                app.ForceAddButton.Icon = 'icon_checkBW.png';
                app.ForceAddButton.Text = 'Confirm';
                app.ForceAddButton.FontSize = 10;
                app.T2 = timer('ExecutionMode','singleShot', 'busymode',"queue" ,"StartDelay",2,"TimerFcn",{@restoreBtn});
                app.T2.start;
                return
            end
            app.T2.stop;%delete(app.T2);app.T2 = [];
            % Use the pipeline figure to select the input source:
            restoreBtn; % Restore button appearence
            % Hide app and focus on pipeFig: 
            app.UIFigure.Visible = 'off';
            figure(app.pipeFig)
            % Change pipefig behaviour so the user can select the input for
            % the selected function.    
            % Get Handles of all buttons:
            allBtn = findall(app.pipeFig,'Type','uicontrol');
            origInfo = struct('Clbk',[],'CData',[],'BcgCol',[]);
            for ii = 1:length(allBtn)
                origInfo(ii).Callback = allBtn(ii).Callback;
                origInfo(ii).CData = allBtn(ii).CData;
                origInfo(ii).BackgroundColor = allBtn(ii).BackgroundColor;
            end            
            % Replace all callbacks:
            arrayfun(@(x) set(x, 'Callback',{@pipeFigPushBtn, allBtn}),allBtn)
            % Add text on top left corner of the figure:
            lab = uicontrol(app.pipeFig,'Style','text','String',['Select input for the function ' FuncSel.name ':'],'FontSize',11);
            lab.Position = [5, app.pipeFig.Position(4) - 40, lab.Extent(3) + 6, 35];
            % Add a confirm button:
            restBtn = uicontrol(app.pipeFig,'Style','pushbutton','String','Reset','BackgroundColor',[.8 .8 .8], 'FontSize',11, 'Callback',{@pipeFigResetBtn, allBtn});
            confBtn = uicontrol(app.pipeFig,'Style','pushbutton','String','Confirm','BackgroundColor',[0 1 0], 'FontSize',11, 'Callback',{@pipeFigConfBtn,origInfo,allBtn, {lab,restBtn}});
            
            % find position of "Disk" button"
            dskPos = allBtn(strcmpi('disk',{allBtn.String})).Position;
            % Relocate confirmation and reset buttons:
            confBtn.Position([1 2 4]) = [5 dskPos(2) dskPos(4)];
            restBtn.Position([1 2 4]) = [sum(confBtn.Position([1 3])+5), dskPos(2), dskPos(4)];
            % Change buttons original colors:
            arrayfun(@(x) set(x, 'CData',[],'BackgroundColor',[.85 .85 .85],'Enable','on'), allBtn);
            inputFcnIndx = 0; % Index in pipeline of the selected input function.
            waitfor(confBtn)
            % Add function to pipeline with selected input:
            if inputFcnIndx == 0                
                state = app.pipeObj.addTask(FuncSel.name, 'inputFrom', '_LOCAL_');
            else
                inputFcn = app.pipeObj.pipe(inputFcnIndx);
                state = app.pipeObj.addTask(FuncSel.name, 'inputFrom', inputFcn.name, 'fromSeq',inputFcn.seq(1,1));
            end
            % Show figure
            app.UIFigure.Visible = 'on';
            if ~state
                uialert(app.UIFigure, ['Failed to add "' FuncSel.name '" to the pipeline!'], 'Operation failed!','Icon', 'error', "CloseFcn",'uiresume(gcbf)')
                uiwait(app.UIFigure);
                return
            end
            % Update figure:            
            app.updatePipeFig;
            
            %%%% Local callbacks-------------------------------------------
            function restoreBtn(~,~)
                % Restores the "Reset" Button text and icon.
                app.ForceAddButton.Text = '';
                app.ForceAddButton.Icon = 'myIcon_forceAddPipe.png';
                app.ForceAddButton.FontSize = 12;
                % Delete timer
                if ~isempty(app.T2)
                    warning('off');
                    delete(app.T2)
                    warning('on')
                end
            end
            
            function pipeFigPushBtn(src,~,mySiblings)
                % Behaviour for the functions and "Disk" buttons in
                % pipeFig.
                % When clicked, the current button becomes green and the
                % all buttons are disabled. The index of the function from
                % the selected button is stored in "inputFcnIndx".
                src.BackgroundColor = [0 .85 0];                             
                arrayfun(@(x) set(x, 'Enable','off'), mySiblings)
                
                % Get pipe index from the selected function:
                inputFcnIndx = src.UserData;
                if isempty(inputFcnIndx)
                    % The "Disk" was selected
                    inputFcnIndx = 0;
                end
            end
            function pipeFigConfBtn(src,~,oldParams, btnArr, otherExtraUI)
                % Confirmation button. Restores the pipeFig to its original
                % state. And adds the function to the pipeline.

                % Restore the buttons' old parameters                
                for jj = 1:length(btnArr)
                    btnArr(jj).Callback = oldParams(jj).Callback;
                    btnArr(jj).BackgroundColor= oldParams(jj).BackgroundColor;
                    btnArr(jj).CData= oldParams(jj).CData;
                    btnArr(jj).Enable = 'on';
                end
                % Delete extra buttons:
                cellfun(@(x) delete(x), otherExtraUI); % Delete Reset button and Label
                delete(src); % Delete self               
            end
            function pipeFigResetBtn(~,~,btnArr)
                % Re-enables all buttons and resets colors to gray.
                arrayfun(@(x) set(x, 'BackgroundColor',[.8 .8 .8], 'Enable','on'), btnArr);
                inputFcnIndx = 0; % Reset input function index.            
            end
        end

        % Callback function
        function SavestepButtonPushed(app, event)
            % This callback opens a dialog file to save a file from a
            % step in the pipeline.
            selection = app.MypipelineListBox.Value;
            if isempty(selection)
                return
            end
%             idx = arrayfun(@(x) isequal(selection,x), app.MypipelineListBox.ItemsData);
%             Color = {[0.07,0.91,1], [0.97,0.44,0.35]};
%             if ~isfield(selection, 'b_save') || isempty(selection.b_save) || ( islogical(selection.b_save) && ~selection.b_save )
%                 selection = addsaveFileName(selection);
%                 if ~selection.b_save
%                     return
%                 end
%                 app.MypipelineListBox.ItemsData(idx).b_save = selection.b_save;
%                 app.MypipelineListBox.ItemsData(idx).saveFileName = selection.saveFileName;
%                 app.SavestepButton.Text = {'Undo', 'Save'};
%                 app.SavestepButton.BackgroundColor = Color{2};
%                 app.MypipelineListBox.Items{idx} =  [app.MypipelineListBox.Items{idx}, '(save as: ', selection.saveFileName ')'];
%             else
%                 app.MypipelineListBox.ItemsData(idx).b_save = false;
%                 app.MypipelineListBox.ItemsData(idx).saveFileName = '';
%                 app.SavestepButton.Text = {'Save' ,'Step'};
%                 app.SavestepButton.BackgroundColor = Color{1};
%                 idx_str = strfind(app.MypipelineListBox.Items{idx}, '(save as: ');
%                 app.MypipelineListBox.Items{idx} = app.MypipelineListBox.Items{idx}(1:idx_str-1);
%             end
%             app.MypipelineListBox.Value = app.MypipelineListBox.ItemsData(idx);
            
            % Local function
            function item = addsaveFileName(item)
                % This functions opens a dialog box so the user types in
                % the filename of the .dat file to be saved.
                item.b_save = false;
                if ~isfield(item, 'saveFileName') || isempty(item.saveFileName)
                    [~,defName,~] = fileparts(item.info.outFileName);
                else
                    defName = item.saveFileName;
                end
                opts.Resize = 'on';
                datName = inputdlg('File name:', 'New .DAT file name', [1 50], {defName}, opts);
                if isempty(datName)
                    disp('Operation Cancelled by User')
                    return
                end
                if ~endsWith(datName{1},'.dat')
                    datName{1} = [datName{1}, '.dat'];
                end
                item.saveFileName = datName{1};
                item.b_save = true;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Launches the execution of the pipeline.
            if isempty(app.pipeObj.pipe)
                uialert(app.UIFigure, 'Create a pipeline first!', 'Failed to save config', 'Icon',"warning");
                return
            end
            % If the user did not verified all functions' parameters, raise
            % a warning:
            idxParams = [app.pipeObj.pipe.b_paramsSet];
            if ~all(idxParams)
                names = {app.pipeObj.pipe(~idxParams).name};
                answer = uiconfirm(app.UIFigure, [{'The parameters of the following functions were not set:'}, names, {'Proceed?'}],...
                    'Warning!',"Icon","warning","CloseFcn",'uiresume(gcbf)', 'Options',{'Yes','No'}, 'DefaultOption',1);
                if strcmpi(answer, 'no')
                    return
                end
            end
            % Validade pipeline first:
            app.pipeObj.validatePipeline;
            % Execute pipeline through mainApp:
            app.mainApp.runPipeline(app.pipeObj, app);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % This callback saves the current pipeline to a .JSON file by
            % calling a method from pipeObj.
            if isempty(app.pipeObj.pipe)
                return
            end
            % If the user did not verified all functions' parameters, raise
            % a warning:
            idx = [app.pipeObj.pipe.b_paramsSet];
            if any(idx)
                names = {app.pipeObj.pipe(idx).name};
                answer = uiconfirm(app.UIFigure, [{'The parameters of the following functions were not set:'}, names, {'Proceed?'}],...
                    'Warning!',"Icon","warning","CloseFcn",'uiresume(gcbf)', 'Options',{'Yes','No'}, 'DefaultOption',1);
                if strcmpi(answer, 'no')
                    return
                end
            end            
            answer = inputdlg('Name of Pipeline:', 'Save Pipe Config File', [1 50]);
            if isempty(answer)
                disp('Operation Cancelled by User')
                % empty pipeline in pipeObj:
                app.pipeObj.pipe = struct();
                return
            end
            answer = answer{:};
            app.pipeObj.savePipe(answer);
            % Bring figure back to front
            figure(app.UIFigure);
        end

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            % This callback loads a saved pipeline from a .JSON file using
            % the "loadPipe" method from pipeObj.            
            if app.b_isStandAlone
                [file,Path] = uigetfile({'*.pipe', 'Pipeline config file (*.pipe)'; '*.json','Pipeline config file (*.json), older version)'}, 'Load Pipeline Config file', 'pipeConfigFile.pipe');
            else
                [file,Path] = uigetfile({'*.pipe', 'Pipeline config file (*.pipe)'; '*.json','Pipeline config file (*.json), older version)'},...
                    'Load Pipeline Config file', fullfile(app.pipeObj.ProtocolObj.SaveDir, 'PipeLineConfigFiles','pipeConfigFile.pipe'));
            end
            
            if file == 0
                disp('Operation Cancelled by User')
                figure(app.UIFigure);
                return
            end
            % Reset function List:
%             app.createFuncList;
            % Load Pipeline from file:
            app.pipeObj.loadPipe(fullfile(Path,file))
            % Update Pipe fig:
            app.updatePipeFig
            % Bring figure to front:
            figure(app.UIFigure);
        end

        % Value changed function: AvailablefunctionsListBox
        function AvailablefunctionsListBoxValueChanged(app, event)
            % Shows the documentation when User clicks on a function.
            if ~app.b_OpenDoc
                return
            end
            % Get the name of the function:
            selection = app.AvailablefunctionsListBox.Value;
            if strcmpi(selection.name, '----------')
                return
            end
            % Search html file inside "doc" folder:
            htmlFile = which([selection.name '.html']);
            if isempty(htmlFile)
                if contains(selection.folder, 'custom', 'IgnoreCase',true)
                    msg = 'Custom functions not supported!';
                else
                    msg = ['Documentation not available for function "' selection.name '". Please contact developers for more info.'];
                end
                uialert(app.UIFigure, msg,'Help failed',"CloseFcn",'uiresume(gcbf)');
                uiwait(app.UIFigure);
                return
            end
            % Display html file in Matlab's browser:           
            web(htmlFile)            
            % Disable "Help mode"
            app.HelpButton.Value = false;
            app.HelpButtonValueChanged;
            
        end

        % Value changed function: HelpButton
        function HelpButtonValueChanged(app, event)
            % If TRUE, when the user clicks on a function from the
            % "Available" functions list, the documentation pops up.
            allChildren = findall(app.UIFigure);
            idx = arrayfun(@(x) isprop(x, 'Enable'), allChildren);
            UIelements = setdiff(allChildren(idx),[app.AvailablefunctionsListBox, app.HelpButton]);
            
            boolMap = containers.Map([true,false],{'on','off'});
            app.b_OpenDoc = app.HelpButton.Value;
            
            b_hasPipeFig = ~isempty(app.pipeFig);
            % Update List tooltip:
            if app.HelpButton.Value
                app.btnState = arrayfun(@(x) strcmpi(x.Enable, 'on'),UIelements);
                app.AvailablefunctionsListBox.Tooltip = 'Click on a function to see documentation!';
                arrayfun(@(x) set(x, 'Enable',boolMap(~app.HelpButton.Value)), UIelements);
            else
                arrayfun(@(x,y) set(x, 'Enable',boolMap(y)), UIelements, app.btnState);
                app.AvailablefunctionsListBox.Tooltip = app.avFcnTooltip;
            end
            % Hide/Show pipe fig:
            if b_hasPipeFig
                app.pipeFig.Visible = boolMap(~app.HelpButton.Value);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clears the current pipeline
            if isempty(app.pipeFig)
                return
            end
            if contains(app.ResetButton.Text, 'reset','IgnoreCase',true) && ( isempty(app.T1) || ~isvalid(app.T1) )
                % Change button to ask for confirmation:
                app.ResetButton.Icon = 'icon_checkBW.png';
                app.ResetButton.Text = 'Confirm';
                app.T1 = timer('ExecutionMode','singleShot', 'busymode',"queue" ,"StartDelay",2,"TimerFcn",@restoreBtn);
                app.T1.start;
                return
            end
            app.T1.stop;delete(app.T1);app.T1 = [];
            app.pipeObj.reset_pipe;
            % Delete figure;
            delete(app.pipeFig); app.pipeFig = [];
            % Disable "Run" and "Save" Buttons:
            app.RunButton.Enable = 'off';
            app.saveButton.Enable = 'off';
            restoreBtn; % Restore button appearence
            % Local callback
            function restoreBtn(~,~)
                % Restores the "Reset" Button text and icon.
                app.ResetButton.Text = 'Reset';
                app.ResetButton.Icon = 'icon_deleteBW.png';
                % Delete timer
                if ~isempty(app.T2)
                    warning('off');
                    delete(app.T2)
                    warning('on')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pipeline Configuration tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AvailablefunctionsPanel = uipanel(app.GridLayout2);
            app.AvailablefunctionsPanel.Title = 'Available functions';
            app.AvailablefunctionsListBox = uilistbox(app.AvailablefunctionsPanel);
            app.AvailablefunctionsListBox.Items = {};
            app.AvailablefunctionsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailablefunctionsListBoxValueChanged, true);
            app.AvailablefunctionsListBox.Tooltip = {'Items with (!) have optional parameters.'; 'Click in "Set Params" to change them.'};
            app.AvailablefunctionsListBox.Value = {};
            app.AddButton = uibutton(app.AvailablefunctionsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = 'icon_plusBW.png';
            app.AddButton.Tooltip = {'Add function to pipeline'};
            app.AddButton.Text = '';
            app.HelpButton = uibutton(app.AvailablefunctionsPanel, 'state');
            app.HelpButton.ValueChangedFcn = createCallbackFcn(app, @HelpButtonValueChanged, true);
            app.HelpButton.Tooltip = {'Click here and select a function from "Available" list fo see the documentation.'};
            app.HelpButton.Text = '?';
            app.ForceAddButton = uibutton(app.AvailablefunctionsPanel, 'push');
            app.ForceAddButton.ButtonPushedFcn = createCallbackFcn(app, @ForceAddButtonPushed, true);
            app.ForceAddButton.Icon = 'myIcon_forceAddPipe.png';
            app.ForceAddButton.Tooltip = {'Force adding function to a new sequence'};
            app.ForceAddButton.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Execute pipeline'};
            app.RunButton.Text = 'Run!';
            app.saveButton = uibutton(app.GridLayout3, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'icon_saveBW.png';
            app.saveButton.Enable = 'off';
            app.saveButton.Tooltip = {'Save pipeline to file'};
            app.saveButton.Text = 'save';
            app.openButton = uibutton(app.GridLayout3, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Icon = 'icon_openFolderBW.png';
            app.openButton.Tooltip = {'Load pipeline from file'};
            app.openButton.Text = 'open';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'icon_deleteBW.png';
            app.ResetButton.Tooltip = {'Erase pipelne'};
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineConfig_dlgBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end