classdef Data_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFIgure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        OptionsPanel                matlab.ui.container.Panel
        XaxisButtonGroup            matlab.ui.container.ButtonGroup
        GroupButton                 matlab.ui.control.ToggleButton
        SubjectButton               matlab.ui.control.ToggleButton
        AcquisitionButton           matlab.ui.control.ToggleButton
        EventButton                 matlab.ui.control.ToggleButton
        ROIButton                   matlab.ui.control.ToggleButton
        GroupsButtonGroup           matlab.ui.container.ButtonGroup
        GroupButton_2               matlab.ui.control.ToggleButton
        SubjectButton_2             matlab.ui.control.ToggleButton
        AcquisitionButton_2         matlab.ui.control.ToggleButton
        EventButton_2               matlab.ui.control.ToggleButton
        ROIButton_2                 matlab.ui.control.ToggleButton
        GroupcolormapDropDownLabel  matlab.ui.control.Label
        GroupcolormapDropDown       matlab.ui.control.DropDown
        DataperplotButtonGroup      matlab.ui.container.ButtonGroup
        GroupButton_3               matlab.ui.control.ToggleButton
        SubjectButton_3             matlab.ui.control.ToggleButton
        AcquisitionButton_3         matlab.ui.control.ToggleButton
        EventButton_3               matlab.ui.control.ToggleButton
        ROIButton_3                 matlab.ui.control.ToggleButton
        GridLayout3                 matlab.ui.container.GridLayout
        ApplyButton                 matlab.ui.control.Button
        SetButton                   matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        StatsObj % "StatsManager" object
        dataOut  % cell array containing the data to be plotted
        gColor % array of color of each grouped item


    end
    
    methods (Access = private)
        
        function loadPrefs(app)
            % LOADPREFS loads the options saved in a .mat file inside umIT's
            % folder.
            disp('Loading preferences...')
            
        end
        
        function pivotData(app)
            % PIVOTDATA rearranges the data in "dataArr" and saves the output
            % in "dataOut".
            disp('Pivoting data')
            
            dimNames = sort(arrayfun(@(x) x.Text, app.XaxisButtonGroup.Children, 'UniformOutput', false));
            indxNames = {'AcquisitionIndx', 'eIndx', 'gIndx', 'rIndx', 'sIndx'};
            dimMap = containers.Map(dimNames, indxNames); % Map dimension names to fieldnames in dataArr.
            % Get dataArr fieldnames from selected dimensions in the order : x-axis, group-data and plot-data:
            selFields = cellfun(@(x) dimMap(x), {app.XaxisButtonGroup.SelectedObject.Text, app.GroupsButtonGroup.SelectedObject.Text, ...
                app.DataperplotButtonGroup.SelectedObject.Text}, 'UniformOutput',false);
            % Get size of output data array:
            dataSz = [length(unique([app.dataArr.(selFields{1})])) length(unique([app.dataArr.(selFields{2})]))];
            % Get size of the output struct:
            outSz = length(unique([app.dataArr.(selFields{3})]));
            out = struct('gIndx', [],'sIndx', [], 'aIndx', [], 'rIndx',[],'eIndx',[],'data',zeros(dataSz, 'single'));
            out = repmat(out, outSz,1);
            
            for ii = 1:length(selFields)
                outSz(ii) = length(unique([app.dataArr.(selFields{ii})]));
            end
            
            
        end
        
        function updateTable(app)
            % UPDATETABLE updates the content of the table to show the
            % Data that will be grouped in each plot and the corresponding
            % color.
            
            disp('updating table...')
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj, b_useGUI)
            app.UIFIgure.Visible = 'off';
            app.StatsObj = statsObj;
            app.loadPrefs;
            if ~b_useGUI
                app.UIFIgureCloseRequest;
            else
                movegui(app.UIFIgure, 'center');
                app.UIFIgure.Visible = 'on';
            end
        end

        % Selection changed function: DataperplotButtonGroup, 
        % GroupsButtonGroup, XaxisButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            currentGroup = event.Source;
            selectedButton = event.Source.SelectedObject;
            otherGroups = setdiff([app.XaxisButtonGroup, app.GroupsButtonGroup, app.DataperplotButtonGroup], currentGroup);
            otherSelect = arrayfun(@(x) x.SelectedObject.Text, otherGroups, 'UniformOutput',false);
            if ismember(selectedButton.Text, otherSelect)
                event.Source.SelectedObject = event.OldValue;
            end
            % Disable apply button:
            app.ApplyButton.Enable = 'off';
        end

        % Close request function: UIFIgure
        function UIFIgureCloseRequest(app, event)
            
            if strcmpi(app.UIFIgure.Visible, 'off')
                delete(app)
            else
                %%%% ASK TO SAVE CHANGES IF DIFFERENT... %%%
                delete(app);
            end
        end

        % Button pushed function: SetButton
        function ConfirmButtonValueChanged(app, event)
            % This callback just populates the items in the UITable.            
                       
            % Populate table with Group Items and colors:
            switch app.GroupsButtonGroup.SelectedObject.Text
                case 'Group'
                    tabItems = unique(app.StatsObj.list_of_groups)';                    
                case 'Subject'
                    tabItems = unique({app.StatsObj.dataArr.SubjectID})';                    
                case 'ROI'
                    tabItems = app.StatsObj.obs_list';                    
                case 'Acquisition'
                    tabItems = arrayfun(@(x) ['Acquisition #' num2str(x)], unique(vertcat(app.StatsObj.dataArr.AcquisitionIndx)), 'UniformOutput',false);
                otherwise
                    tabItems = app.StatsObj.list_of_events;                                         
            end
            b_hasEvents = all(~strcmpi(app.StatsObj.list_of_events, 'noevents'));
            if b_hasEvents && ~strcmpi(app.DataperplotButtonGroup.SelectedObject.Text, 'event')
                % If events exist and it was not selected to be presented as plots, split the grouped data by event
                % in the order:
                % "Item1-ev1, Item1-ev2, Item1-evN, Item2-ev1, Item2-ev2, Item2-evN ..."                
                tmp = cell(length(tabItems)*length(app.StatsObj.list_of_events),1);
                cnt = 1;
                for ii = 1:length(tabItems)
                    tmp(cnt:cnt+length(app.StatsObj.list_of_events)-1) = cellfun(@(x) [tabItems{ii} ' - '  x], app.StatsObj.list_of_events, 'UniformOutput',false)';
                    cnt = cnt+length(app.StatsObj.list_of_events);
                end
                tabItems = tmp; clear tmp                
            end
            eval(['tabColors = ' app.GroupcolormapDropDown.Value '(' num2str(numel(tabItems)) ');']);
            % Populate the table with the data and colors:            
            app.UITable.Data = table(tabItems);
            app.UITable.RowName = 1:height(app.UITable.Data);            
            app.UITable.BackgroundColor = tabColors;     
            app.UITable.Enable = 'on';
            % 
            app.ApplyButton.Enable = 'on';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % This callback from UITable so the user can click on an item
            % to change its color.                                    
            if size(event.Indices,1)>1 
                return
            end       
            pause(.5)
            app.UITable.BackgroundColor(event.Indices(1),:) = uisetcolor(app.UITable.BackgroundColor(event.Indices(1),:), 'Choose a color');            
            figure(app.UIFIgure);
        end

        % Value changed function: GroupcolormapDropDown
        function GroupcolormapDropDownValueChanged(app, event)
            % Just to disable apply button and force user to click on Set
            % button.
            app.ApplyButton.Enable = 'off';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % This callback reorganizes the data stored in "dataArr" of 
            % StatsObj in a cell array. Each cell corresponds to the data
            % from a single plot.
            
            disp('Repackaging data...')            


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFIgure = uifigure('Visible', 'off');
            app.UIFIgure.Name = 'Data selector';
            app.UIFIgure.CloseRequestFcn = createCallbackFcn(app, @UIFIgureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFIgure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OptionsPanel = uipanel(app.GridLayout2);
            app.OptionsPanel.Title = 'Options';
            app.XaxisButtonGroup = uibuttongroup(app.OptionsPanel);
            app.XaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.XaxisButtonGroup.Title = 'X axis';
            app.GroupButton = uitogglebutton(app.XaxisButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjectButton = uitogglebutton(app.XaxisButtonGroup);
            app.SubjectButton.Text = 'Subject';
            app.AcquisitionButton = uitogglebutton(app.XaxisButtonGroup);
            app.AcquisitionButton.Text = 'Acquisition';
            app.AcquisitionButton.Value = true;
            app.EventButton = uitogglebutton(app.XaxisButtonGroup);
            app.EventButton.Text = 'Event';
            app.ROIButton = uitogglebutton(app.XaxisButtonGroup);
            app.ROIButton.Text = 'ROI';
            app.GroupsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.GroupsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GroupsButtonGroup.Title = 'Group(s)';
            app.GroupButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.GroupButton_2.Text = 'Group';
            app.GroupButton_2.Value = true;
            app.SubjectButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.SubjectButton_2.Text = 'Subject';
            app.AcquisitionButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.AcquisitionButton_2.Text = 'Acquisition';
            app.EventButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.EventButton_2.Text = 'Event';
            app.ROIButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.ROIButton_2.Text = 'ROI';
            app.GroupcolormapDropDownLabel = uilabel(app.OptionsPanel);
            app.GroupcolormapDropDownLabel.Text = 'Group colormap';
            app.GroupcolormapDropDown = uidropdown(app.OptionsPanel);
            app.GroupcolormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.GroupcolormapDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupcolormapDropDownValueChanged, true);
            app.GroupcolormapDropDown.Value = 'parula';
            app.DataperplotButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DataperplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DataperplotButtonGroup.Title = 'Data per plot';
            app.GroupButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.GroupButton_3.Text = 'Group';
            app.SubjectButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.SubjectButton_3.Text = 'Subject';
            app.AcquisitionButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.AcquisitionButton_3.Text = 'Acquisition';
            app.EventButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.EventButton_3.Text = 'Event';
            app.ROIButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.ROIButton_3.Text = 'ROI';
            app.ROIButton_3.Value = true;
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.SetButton = uibutton(app.GridLayout3, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.SetButton.Text = 'Set';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Group Name'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.UIFIgure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFIgure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFIgure)
        end
    end
end