classdef lsaToolboxGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectMenu                    matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        UpdateMenu                     matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        DashboardTab                   matlab.ui.container.Tab
        Tree                           matlab.ui.container.Tree
        UITable                        matlab.ui.control.Table
        ProjectStatusPanel             matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        LastoperationsruninselecteditemLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        PropertiesofselecteditemLabel  matlab.ui.control.Label
        PipelineTab                    matlab.ui.container.Tab
        Tree_2                         matlab.ui.container.Tree
        AvailableoperationsListBoxLabel  matlab.ui.control.Label
        AvailableoperationsListBox     matlab.ui.control.ListBox
        PipelineListBoxLabel           matlab.ui.control.Label
        PipelineListBox                matlab.ui.control.ListBox
        RunPipelineButton              matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        SelectLevelButtonGroup         matlab.ui.container.ButtonGroup
        SubjectButton                  matlab.ui.control.RadioButton
        AcquisitionButton              matlab.ui.control.RadioButton
        ModalityButton                 matlab.ui.control.RadioButton
        DataVisualizationTab           matlab.ui.container.Tab
        ContextMenu                    matlab.ui.container.ContextMenu
        EditMenu                       matlab.ui.container.Menu
        DeleteMenu                     matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        EditMenu_2                     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        ProtocolObj % PROTOCOL Object
        ProtocolFile % Protocol File Path
        TreeNodeSelection % Tree Node(s) selected by user in Dashboard.
        TreeNodeList % List of UITREENODE objects.
    end
    
    methods (Access = private)
        
        function check4newData(app)
            % Checks the Raw folder of PROTOCOL for new/suppressed Subjects
            % and Acquisitions.
            app.Label.Text = 'Checking for new Data in Raw Directory...';
            pause(1);
            newprotocol = app.ProtocolObj.ProtoFunc(app.ProtocolObj);
            newprotocol = app.ProtocolObj.removeGarbage(newprotocol);
            iNewSubj = ~ismember({newprotocol.ID}, {app.ProtocolObj.Array.ObjList.ID}); % New subjects in the newprotocol.
            iMissSubj = ~ismember({app.ProtocolObj.Array.ObjList.ID},{newprotocol.ID});% Subjects from original list that are no longer in the newprotocol.
            indNwArr = find(~iNewSubj);
            indprotocol = arrayfun(@(a) find(strcmp({newprotocol(indNwArr).ID}, a)),...
                {app.ProtocolObj.Array.ObjList.ID}, 'UniformOutput', false); indprotocol = [indprotocol{:}];
            iNewAcq = arrayfun(@(a,b) ~ismember({a.Array.ObjList.ID}, {b.Array.ObjList.ID}),...
                newprotocol(indNwArr), app.ProtocolObj.Array.ObjList(indprotocol), 'UniformOutput', false);
            iNewAcq = [iNewAcq{:}];
            iMissAcq = arrayfun(@(a,b) ~ismember({b.Array.ObjList.ID}, {a.Array.ObjList.ID}),...
                newprotocol(indNwArr), app.ProtocolObj.Array.ObjList(indprotocol), 'UniformOutput', false);
            iMissAcq = [iMissAcq{:}];
            if any([iNewSubj iMissSubj iNewAcq iMissAcq])
                ns = num2str(sum(iNewSubj));
                ms = num2str(sum(iMissSubj));
                na = num2str(sum(iNewAcq));
                ma = num2str(sum(iMissAcq));
                app.Label.Text = 'Changes in the raw folder Detected:';
                app.Label_2.Text = sprintf('%s \n%s \n%s \n%s \n%s',['New Subjects: ' ns], ['Missing Subjects: ' ms], ...
                    ['New Acquisitions: ' na], ['Missing Acquisitions: ' ma], 'Click on ''Project'' >> ''Update Project'' to apply changes!');
            else
                app.Label.Text = 'All Raw Files read.';
                app.Label_2.Text = '';
            end
            
        end
        
        function makeUItreeNodes(app, Tree, Toggle_MultiSelect)
            % Create UITree nodes of Protocol hierarchy.
            Tree.Multiselect = Toggle_MultiSelect;
            app.TreeNodeList = struct();
            delete(Tree.Children)
            root = uitreenode(Tree, 'Text', app.ProtocolObj.Name, 'NodeData', app.ProtocolObj, 'Icon', 'icon_WinFolder.png');
            for i = 1:length(app.ProtocolObj.Array.ObjList)
                tmpS = app.ProtocolObj.Array.ObjList(i);
                eval(['app.TreeNodeList.s' num2str(i) ' = uitreenode(root, ''Text'', tmpS.ID, ''NodeData'', tmpS, ''Icon'', ''icon_WinFolder.png'');']);
                for j = 1:length(tmpS.Array.ObjList)
                    tmpA = tmpS.Array.ObjList(j);
                    eval(['app.TreeNodeList.s' num2str(i) 'a' num2str(j) ' = uitreenode(app.TreeNodeList.s' num2str(i) ', ''Text'', tmpA.ID, ''NodeData'', tmpA, ''Icon'', ''icon_WinFolder.png'');']);
                    for k = 1:length(tmpA.Array.ObjList)
                        tmpM = tmpA.Array.ObjList(k);
                        eval(['app.TreeNodeList.s' num2str(i) 'a' num2str(j) 'm' num2str(k) ' = uitreenode(app.TreeNodeList.s' num2str(i) 'a' num2str(j) ', ''Text'', tmpM.ID, ''NodeData'', tmpM, ''Icon'', ''icon_WinFolder.png'');']);
                    end
                end
            end
            expand(Tree)
            app.checkTree;
        end
        
        function displayNodeInfo(app)
            NodeObj = app.TreeNodeSelection.NodeData;
            KeySet = properties(NodeObj); KeySet = setdiff(KeySet, {'Array', 'LastLog', 'garbageList'});
            for ii = 1:length(KeySet)
                eval(['propVals{ii} = NodeObj.' KeySet{ii} ';'])
            end
            classInfo = {'Type', class(NodeObj)};
            NodeObjProps = horzcat(KeySet, propVals');
            NodeObjProps = vertcat(classInfo, NodeObjProps);
            % Temporary solution for displaying Function Handle...
            if isa(NodeObj,'Protocol')
                NodeObjProps{4,2} = char(NodeObjProps{4,2});
            end
            %%%
            Data = [];
            % The if statement here controls for known property data types.
            % Has to be improved to be more flexible.
            for i = 1:size(NodeObjProps,1)
                tag = string([NodeObjProps{i,1} ':']);
                if isnumeric(NodeObjProps{i,2})
                    value = string(['    ' num2str(NodeObjProps{i,2})]);
                elseif iscell(NodeObjProps{i,2})
                    value = string(NodeObjProps{i,2}');
                else
                    value = string(['    ' NodeObjProps{i,2}]);
                end
                
                Data = vertcat(Data, tag, value);
            end
            app.TextArea.Value = Data;
            app.TextArea.Editable = "off";
            ColNames = {'Function', 'Completed (0-1)', 'Date and Time', 'Messages'};
            if isempty(NodeObj.LastLog)
                tableData = {'None', 0, 'None', 'None'};
            else
                tableData = NodeObj.LastLog(:,5:8);
                tableData = table2cell(tableData);
                tableData = cellfun(@(x) char(string(x)), tableData, 'UniformOutput',false);
            end
            app.UITable.Data = tableData;
            app.UITable.ColumnName = ColNames;
            app.UITable.ColumnEditable = false;
        end
        
        function checkTree(app)
            % This function checks if the hierarchical tree has all
            % necessary elements for analysis. This means that at least one
            % Subject has one Acquisition that itself has one modality.
            
            idxS = arrayfun(@(x) isempty(x.Array.ObjList), app.ProtocolObj.Array.ObjList); % Empty Subject
            faultyS = find(idxS);
            faultyS_ID = arrayfun(@(x) app.ProtocolObj.Array.ObjList(x).ID, faultyS, 'UniformOutput',false);
            
            otherS = find(~idxS);
            faultyA_ID = [];
            for i = 1:length(otherS)
                faultyA = find(arrayfun(@(x) isempty(x), app.ProtocolObj.Array.ObjList(otherS(i)).Array.ObjList)); % Empty Acquisitions.
                tmp = arrayfun(@(x) app.ProtocolObj.Array.ObjList(otherS(i)).Array.ObjList(x).ID, faultyA, 'UniformOutput',false);
                faultyA_ID = [faultyA_ID tmp];
            end
            
            faultyList = [faultyS_ID faultyA_ID];
            fields = fieldnames(app.TreeNodeList);
            for i = 1:length(fields)
                eval(['NodeID{' num2str(i) '} = app.TreeNodeList.' fields{i} '.Text;'])
            end
            if ~isempty(faultyList)
                for i = 1:length(faultyList)
                    idx = strcmp(faultyList{i}, NodeID);
                    if any(idx)
                        eval(['app.TreeNodeList.' fields{idx} '.Icon = ''icon_Error.png'';'])
                    end
                    
                end
                uiwait(warndlg(['One or more objects are empty. '...
                    'Each Subject has to have at least one Acquisition with at least one Modality.' ...
                    ' Please, correct this before continuing with the analysis.'], 'Warning!'));
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('D:\Academico\PostDoc_UdeM\LabeoTech\IsaToolbox'));
            addpath(genpath('D:\Academico\PostDoc_UdeM\LabeoTech\ioi_ana'));
            clearvars
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, path] = uigetfile('*mat', 'Open a Project File');
            app.ProtocolFile = fullfile(path, filename);
            cd(path)
            a = load(filename);
            protocolName = fieldnames(a);protocolName = protocolName{1};
            eval(['protocol = a.' protocolName ';'])
            if isa(protocol, 'Protocol')
                eval(['app.ProtocolObj = a.' protocolName ';'])
                app.Label.Text = sprintf('%s', 'Project file loaded!');
                app.Label_2.Text = sprintf('%s', ['Raw Dir:' protocol.MainDir]);
                app.check4newData;
                app.makeUItreeNodes(app.Tree, "off");
            else
                app.Label.Text = sprintf('%s\n%s', ['The file ' filename ' does not contain a valid Project.'], 'Try again!');
            end
        end

        % Menu selected function: UpdateMenu
        function UpdateMenuSelected(app, event)
            
            if ~isempty(app.ProtocolObj)
                app.Label.Text = 'Updating Project Files...';
                app.ProtocolObj.updateList;
                app.check4newData;
                app.makeUItreeNodes(app.Tree, "off");
            else
                errordlg('No Project Found!')
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            protocol = app.ProtocolObj;
            if isempty(app.ProtocolFile)
                app.ProtocolFile= uigetdir(app.ProtocolObj.SaveDir, 'Select Folder to Save Project File');
                app.ProtocolFile = fullfile(app.ProtocolFile, [app.ProtocolObj.Name '.mat']);
                save(app.ProtocolFile, "protocol");
                msgbox('Project Saved!');
            else
                save(app.ProtocolFile, 'protocol');
                uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % Reset Protocol Object and FileName
            app.ProtocolObj = [];
            app.ProtocolFile = [];
            %%
            
            app.Label.Text = 'Creating New Project File...';
            app.Label_2.Text = '...';
            Name = inputdlg('Type the Name of your project: ', 'Project Name', [1 60], {''}, 'on');
            if isempty(Name{:})
                warndlg('Operation Cancelled', 'Warning!', "modal")
                return
            end
            MainDir = uigetdir(pwd, 'Select Directory with Raw Data.');
            SaveDir = uigetdir(pwd, 'Select Save Directory.');
            [ProtoFunc, FuncPath] = uigetfile('*.m', 'Select Protocol Function.');
            ProtoFunc = erase(ProtoFunc, '.m');
            addpath(genpath(MainDir));
            addpath(genpath(SaveDir));
            addpath(genpath(FuncPath));
            cd(SaveDir);
            eval(['FuncHandle = @' ProtoFunc]);
            app.ProtocolObj = Protocol(Name{:},MainDir, SaveDir,FuncHandle, []);
            app.Label.Text = 'New Project Created!';
            app.Label_2.Text = 'Creating Object Arrays...';
            app.ProtocolObj.generateList;
            app.Label_2.Text = 'Creating Save Directories...';
            app.ProtocolObj.generateSaveFolders;
            app.Label_2.Text = 'All set! Don''t forget to save it.';
            app.makeUItreeNodes(app.Tree, "off");
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.TreeNodeSelection = app.Tree.SelectedNodes;
            app.displayNodeInfo;
            if isa(app.TreeNodeSelection.NodeData, 'Protocol')
                return
            end
            
            fields = fieldnames(app.TreeNodeList);
            for i = 1:length(fields)
                eval(['NodeFolderList{' num2str(i) '} = app.TreeNodeList.' fields{i} '.NodeData.SaveFolder;'])
            end
            
            NodeFolder = app.TreeNodeSelection.NodeData.SaveFolder;
            idx = strcmp(NodeFolder, NodeFolderList);
            
            if any(idx & contains(fields, 'm')')
                eval(['app.TreeNodeList.' fields{idx} '.ContextMenu = app.ContextMenu2;'])
            else
                eval(['app.TreeNodeList.' fields{idx} '.ContextMenu = app.ContextMenu;'])
            end
            others = find(~idx);
            for i = 1:length(others)
                eval(['app.TreeNodeList.' fields{others(i)} '.ContextMenu = gobjects(0);'])
            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            onlyChild = false;
            if isempty(setdiff(app.TreeNodeSelection.NodeData.ID, app.TreeNodeSelection.Parent.NodeData.Array.listProp('ID')))
                onlyChild = true;
            end
            if onlyChild
                selection = uiconfirm(app.UIFigure,['You are about to delete ' app.TreeNodeSelection.Text '. This is the only element inside ' ...
                    app.TreeNodeSelection.Parent.Text '. This will also delete ' app.TreeNodeSelection.Parent.Text '. Proceed?'], 'Delete Element?',...
                    'Icon','warning');
            else
                selection = uiconfirm(app.UIFigure,['You are about to delete ' app.TreeNodeSelection.Text '. Proceed?'], 'Delete Element?',...
                    'Icon','warning');
            end
            
            if strcmp(selection, 'OK') && onlyChild
                IDparent = app.TreeNodeSelection.Parent.NodeData.ID;
                idxParent = app.TreeNodeSelection.Parent.Parent.NodeData.Array.findElement('ID', IDparent);
                out = app.TreeNodeSelection.Parent.Parent.NodeData.Array.removeObj(idxParent);
                app.ProtocolObj.garbageList = [app.ProtocolObj.garbageList; out]; % add deleted item to garbage list.
                app.makeUItreeNodes(app.Tree, "off"); % Re-create Tree.
            elseif strcmp(selection, 'OK') && ~onlyChild
                ID = app.TreeNodeSelection.NodeData.ID;
                idx = app.TreeNodeSelection.Parent.NodeData.Array.findElement('ID', ID);
                out = app.TreeNodeSelection.Parent.NodeData.Array.removeObj(idx);
                app.ProtocolObj.garbageList = [app.ProtocolObj.garbageList; out]; % add deleted item to garbage list.
                app.makeUItreeNodes(app.Tree, "off"); % Re-create Tree.
            end
        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
            propList = properties(app.TreeNodeSelection.NodeData);
            propList = setdiff(propList, {'ID', ...
                'Array', 'RawFiles', 'RawFolder'});
            isSettable = false([1 length(propList)]);
            isNum = isSettable;
            for i = 1:length(propList)
                propInfo = findprop(app.TreeNodeSelection.NodeData, propList{i});
                eval(['val{i} = num2str(app.TreeNodeSelection.NodeData.' propList{i} ');'])
                if strcmp(propInfo.SetAccess, 'public')
                    isSettable(i) = true;
                end
            end
            if ~any(isSettable)
                msgbox('There are no editable properties for this object.', 'modal')
                return
            end
            prompt = propList(isSettable);
            val = val(isSettable);
            dlgTitle = ['Editor : ' app.TreeNodeSelection.Text];
            answer = inputdlg(prompt, dlgTitle, 1, val, "on");
            if ~isempty(answer)
                for i = 1:length(prompt)
                    try
                        if ~isnan(str2double(answer{i}))
                            disp(answer{i})
                            eval(['app.TreeNodeSelection.NodeData.' prompt{i} ' = str2double(answer{' num2str(i) ...
                                '});'])
                        else
                            eval(['app.TreeNodeSelection.NodeData.' prompt{i} ' = answer{' num2str(i) ...
                                '};'])
                        end
                    catch ME
                        errordlg(getReport(ME, 'basic'), 'Error!')
                    end
                end
                app.displayNodeInfo;
            else
                return
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isempty(app.ProtocolObj)
                delete(app)
            elseif isempty(app.ProtocolFile)
                msg = 'You Created a new Project. Do you want to save it?';
                title = 'Save new Project';
                selection = uiconfirm(app.UIFigure, msg, title, 'Options',{'Save', 'No', 'Cancel'}, ...
                   "DefaultOption",1, "CancelOption",3);
                switch selection
                    case 'Save'
                        protocol = app.ProtocolObj;
                        app.ProtocolFile= uigetdir(protocol.SaveDir, 'Select Folder to Save Project File');
                        app.ProtocolFile = fullfile(app.ProtocolFile, [protocol.Name '.mat']);
                        save(app.ProtocolFile, "protocol");
                        msgbox('Project Saved!');
                        delete(app);
                    case 'No'
                        delete(app)
                    case 'Cancel'
                        return
                end
            else
                a = load(app.ProtocolFile);
                if ~isequal(a.protocol, app.ProtocolObj)
                    msg = 'It seems that you made changes to your Project. Do you want to save them?';
                    title = 'Confirm Save';
                    selection = uiconfirm(app.UIFigure,msg,title,...
                        'Options',{'Save','No', 'Cancel'},...
                        'DefaultOption',1,'CancelOption',3);
                    switch selection
                        case 'Save'
                            protocol = app.ProtocolObj;
                            save(app.ProtocolFile, "protocol");
                            uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
                            delete(app)
                        case 'No'
                            delete(app)
                        case 'Cancel'
                            return
                    end
                else
                    delete(app)
                end
            end
        end

        % Menu selected function: EditMenu_2
        function EditMenu_2Selected(app, event)
            app.EditMenuSelected(event)
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
                protocol = app.ProtocolObj;
                cd(protocol.SaveDir);
                uisave('protocol', [protocol.Name '.mat']);
                uiwait(msgbox('Project file saved!'));
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if isempty(app.ProtocolObj)
                uiwait(warndlg('You have to load/create a Project File first!', 'Warning!', 'modal'));
                return
            end
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Dashboard'
                    app.makeUItreeNodes(app.Tree, "off");
                case 'Pipeline'
                    app.makeUItreeNodes(app.Tree_2, "on");
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewMenu = uimenu(app.ProjectMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.UpdateMenu = uimenu(app.ProjectMenu);
            app.UpdateMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateMenuSelected, true);
            app.UpdateMenu.Text = 'Update';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.ProjectMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as ...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.Tree = uitree(app.DashboardTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UITable = uitable(app.DashboardTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ProjectStatusPanel = uipanel(app.DashboardTab);
            app.ProjectStatusPanel.Title = 'Project Status';
            app.Label = uilabel(app.ProjectStatusPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ProjectStatusPanel);
            app.Label_2.Text = '';
            app.LastoperationsruninselecteditemLabel = uilabel(app.DashboardTab);
            app.LastoperationsruninselecteditemLabel.Text = 'Last operations run in selected item:';
            app.TextArea = uitextarea(app.DashboardTab);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.PropertiesofselecteditemLabel = uilabel(app.DashboardTab);
            app.PropertiesofselecteditemLabel.Text = 'Properties of selected item:';
            app.PipelineTab = uitab(app.TabGroup);
            app.PipelineTab.Title = 'Pipeline';
            app.Tree_2 = uitree(app.PipelineTab);
            app.AvailableoperationsListBoxLabel = uilabel(app.PipelineTab);
            app.AvailableoperationsListBoxLabel.Text = 'Available operations';
            app.AvailableoperationsListBox = uilistbox(app.PipelineTab);
            app.PipelineListBoxLabel = uilabel(app.PipelineTab);
            app.PipelineListBoxLabel.Text = 'Pipeline';
            app.PipelineListBox = uilistbox(app.PipelineTab);
            app.RunPipelineButton = uibutton(app.PipelineTab, 'push');
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.Button = uibutton(app.PipelineTab, 'push');
            app.Button_2 = uibutton(app.PipelineTab, 'push');
            app.SelectLevelButtonGroup = uibuttongroup(app.PipelineTab);
            app.SelectLevelButtonGroup.Title = 'Select Level';
            app.SubjectButton = uiradiobutton(app.SelectLevelButtonGroup);
            app.SubjectButton.Text = 'Subject';
            app.SubjectButton.Value = true;
            app.AcquisitionButton = uiradiobutton(app.SelectLevelButtonGroup);
            app.AcquisitionButton.Text = 'Acquisition';
            app.ModalityButton = uiradiobutton(app.SelectLevelButtonGroup);
            app.ModalityButton.Text = 'Modality';
            app.DataVisualizationTab = uitab(app.TabGroup);
            app.DataVisualizationTab.Title = 'Data Visualization';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.EditMenu = uimenu(app.ContextMenu);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = 'Edit';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.EditMenu_2 = uimenu(app.ContextMenu2);
            app.EditMenu_2.MenuSelectedFcn = createCallbackFcn(app, @EditMenu_2Selected, true);
            app.EditMenu_2.Text = 'Edit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsaToolboxGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end